classdef FinalGrabar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FraseEditField               matlab.ui.control.EditField
        FraseEditFieldLabel          matlab.ui.control.Label
        DatasetNombre                matlab.ui.control.EditField
        DatasetEditFieldLabel        matlab.ui.control.Label
        EscogerDatasetButton         matlab.ui.control.Button
        ContinuarButton              matlab.ui.control.Button
        LabelTop                     matlab.ui.control.Label
        RepeticionEditField          matlab.ui.control.NumericEditField
        RepeticionEditFieldLabel     matlab.ui.control.Label
        IdentificadorEditField       matlab.ui.control.EditField
        IdentificadorEditFieldLabel  matlab.ui.control.Label
        SexoDropDown                 matlab.ui.control.DropDown
        SexoDropDownLabel            matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    properties (Access = private)
        Etapa = 0
        dataset
        genero
        archivo = 'dataset_emg.mat' % Por defecto crea un dataset con este nombre
        sobreescribir = 0 % solucion "temporal"
        omitir = 0 % tambien es una solucion "temporal"
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ContinuarButton
        function ContinuarButtonPushed(app, event)
            switch app.Etapa 
                case 0
                    % =========================
                    % CARGAR O CREAR DATASET
                    % =========================
                    if (ischar(app.archivo) || isstring(app.archivo)) && exist(app.archivo, 'file')
                        load(app.archivo, 'dts');
                        app.dataset = dts;
                    else
                        app.dataset = struct([]);
                    end
                    
                    % =========================
                    % BUSCAR SI EL SUJETO YA EXISTE
                    % =========================
                    indicesSujeto = [];
                    for i = 1:length(app.dataset)
                        if strcmpi(app.dataset(i).idSujeto, app.IdentificadorEditField.Value)
                            indicesSujeto(end+1) = i;
                        end
                    end
        
                    % =========================
                    % GRABAR GÉNERO SOLO SI ES NUEVO
                    % =========================
                    if isempty(indicesSujeto)
                        app.LabelTop.Text = "Nuevo ID registrado. Presiona Continuar para seguir.";
                        app.genero = app.SexoDropDown.Value;
                    else
                        app.LabelTop.Text = "ID ya existente. Presiona Continuar para seguir.";
                        app.genero = app.dataset(indicesSujeto(1)).genero;
                    end
                    app.Etapa = 1;
                    app.SexoDropDown.Enable = "off";
                    app.IdentificadorEditField.Enable = "off";
                    app.EscogerDatasetButton.Enable = "off";
                    app.FraseEditField.Enable = "off";
                    
                case {1,2,3,4,5}
                    app.sobreescribir = 0;
                    app.omitir = 0;
                    app.RepeticionEditField.Value = app.Etapa;
                    
                    % Ver si esa repetición ya existe
                    indiceExistente = [];
                    for i = 1:length(app.dataset)
                        if strcmpi(app.dataset(i).idSujeto, app.IdentificadorEditField.Value) && app.dataset(i).repeticion == app.Etapa
                            indiceExistente = i;
                            break;
                        end
                    end
                    
                    if ~isempty(indiceExistente)
                        answer = uiconfirm(app.UIFigure, '¿Deseas sobreescribir esta repeticion?', ('Repeticion #' + string(app.Etapa) + ' ya existe'), 'Options', {'Si', 'No'});
                        if strcmp(answer, 'Si')
                            app.sobreescribir = 1;
                        else
                            app.omitir = 1;
                        end
                    else
                        answer = uiconfirm(app.UIFigure, '¿Que deseas hacer con esta repeticion?', ('Repeticion #' + string(app.Etapa)), 'Options', {'Capturar', 'Omitir'});
                        if strcmp(answer, 'Omitir')
                            app.omitir = 1;
                        end
                    end
                    
                    % ===================== INICIO SEÑALES ===============
                    senal = [];
                    if app.omitir == 0 
                        app.ContinuarButton.Enable = "off";
                        
                        % Configuración del puerto serial
                        port = "COM4";          
                        baudRate = 115200;
                        
                        s = serialport(port, baudRate);
                        flush(s);
                        
                        % Parámetros de adquisición
                        Fs = 1000;              
                        Ts = 1/Fs;
                        
                        hablar = 2500; 
                        silencio = 1000; 
                        N = (2*silencio) + hablar; 
                        
                        ceros = zeros(1, N);
                        data = [ceros; ceros];
                        time = (0:N-1)*Ts;
                        
                        plot(app.UIAxes, time, data);
                        
                        updateEvery = 250;
                        i = 1;
                        counter = 0;
                        
                        app.LabelTop.Text = "Silencio...";
                        app.LabelTop.BackgroundColor = "yellow";
                        app.LabelTop.FontColor = "black";
                        
                        while i < N+1
                            if s.NumBytesAvailable > 0
                                raw = readline(s);
                                twovalue = split(raw);
                                
                                % CORRECCIÓN 3: Evitar caídas si la trama viene incompleta
                                if numel(twovalue) >= 2
                                    value1 = str2double(twovalue(1,1));
                                    value2 = str2double(twovalue(2,1));
                                    
                                    if ~isnan(value1) && ~isnan(value2)
                                        data(1,i) = value1;
                                        data(2,i) = value2;
                                        i = i + 1;
                                        counter = counter + 1;
                            
                                        if counter >= updateEvery
                                            plot(app.UIAxes, time, data);
                                            counter = 0;
                                        end
                                        switch i
                                            case silencio
                                                app.LabelTop.Text = "Habla!";
                                                app.LabelTop.BackgroundColor = "green";
                                            case (silencio + hablar)
                                                app.LabelTop.Text = "Silencio...";
                                                app.LabelTop.BackgroundColor = "yellow";
                                        end
                                    end
                                end
                            end
                        end
                        senal = data;
                        plot(app.UIAxes, time, senal);
                        app.LabelTop.BackgroundColor = "black";
                        app.LabelTop.FontColor = "white";
                        app.ContinuarButton.Enable = "on";
                        
                        % CORRECCIÓN 1: Cerrar y liberar el puerto serial de inmediato
                        clear s; 
                    end
                    % ===================== FINAL SEÑALES ================
                    
                    % =========================
                    % VALIDACIONES
                    % =========================
                    % CORRECCIÓN 2: Validar la dimensión 1 (filas = canales)
                    if size(senal, 1) ~= 2 && app.omitir == 0
                        app.LabelTop.Text = 'Error: la señal debe tener 2 canales.';
                        return; % Detiene la ejecución si está corrupto
                    end
                    
                    if any(isnan(senal), 'all') && app.omitir == 0
                        app.LabelTop.Text = 'Error: la señal contiene NaN.';
                        return;
                    end
                    
                    if any(isinf(senal), 'all') && app.omitir == 0
                        app.LabelTop.Text = 'Error: la señal contiene Inf.';
                        return;
                    end
                    
                    % =========================
                    % CREAR REGISTRO Y GUARDAR
                    % =========================
                    if app.omitir == 0 || app.sobreescribir == 1
                        registro.idSujeto = app.IdentificadorEditField.Value;
                        registro.genero = app.genero;
                        registro.repeticion = app.Etapa;
                        registro.senal = senal;
                        registro.frase = app.FraseEditField.Value;
                    end
                    
                    if isempty(app.dataset) && (app.omitir == 0)
                        app.dataset = registro;
                        app.LabelTop.Text = 'Primera repeticion del nuevo registro guardada.';
                    elseif isempty(indiceExistente) && (app.omitir == 0)
                        app.dataset(end+1) = registro;
                        app.LabelTop.Text = 'Repeticion #' + string(app.Etapa) + ' guardada.';
                    elseif app.sobreescribir == 1
                        app.dataset(indiceExistente) = registro;
                        app.LabelTop.Text = 'Repeticion #' + string(app.Etapa) + ' sobreescrita.';
                    elseif app.omitir == 1
                        app.LabelTop.Text = 'Repeticion #' + string(app.Etapa) + ' omitida.';
                    else
                        app.LabelTop.Text = "Proceso terminado.";
                    end
                    
                    dts = app.dataset;
                    save(app.archivo, 'dts');
                    
                    if app.Etapa == 5
                        answer = uiconfirm(app.UIFigure, '¿Deseas ingresar otro sujeto?', 'Registro finalizado', 'Options', {'Si', 'No'});
                        if strcmp(answer, 'No')
                            delete(app);
                            return;
                        end
                        app.Etapa = 0;
                        app.LabelTop.Text = "Ingresa los datos del sujeto.";
                        app.EscogerDatasetButton.Enable = "on";
                        app.SexoDropDown.Enable = "on";
                        app.IdentificadorEditField.Enable = "on";
                        app.FraseEditField.Enable = "on";
                        app.RepeticionEditField.Value = 0;
                    else
                        app.Etapa = app.Etapa + 1;
                    end
            end
        end

        % Button pushed function: EscogerDatasetButton
        function EscogerDatasetButtonPushed(app, event)
            app.archivo = uigetfile;
            app.DatasetNombre.Value = string(app.archivo);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Señal Electrica en tiempo real')
            xlabel(app.UIAxes, 'Tiempo (s)')
            ylabel(app.UIAxes, 'Voltaje (V)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLimitMethod = 'tight';
            app.SexoDropDownLabel = uilabel(app.UIFigure);
            app.SexoDropDownLabel.Text = 'Sexo';
            app.SexoDropDown = uidropdown(app.UIFigure);
            app.SexoDropDown.Items = {'Masculino', 'Femenino'};
            app.SexoDropDown.Value = 'Masculino';
            app.IdentificadorEditFieldLabel = uilabel(app.UIFigure);
            app.IdentificadorEditFieldLabel.Text = 'Identificador';
            app.IdentificadorEditField = uieditfield(app.UIFigure, 'text');
            app.RepeticionEditFieldLabel = uilabel(app.UIFigure);
            app.RepeticionEditFieldLabel.Enable = 'off';
            app.RepeticionEditFieldLabel.Text = 'Repeticion';
            app.RepeticionEditField = uieditfield(app.UIFigure, 'numeric');
            app.RepeticionEditField.Enable = 'off';
            app.LabelTop = uilabel(app.UIFigure);
            app.LabelTop.Text = 'Ingresa los datos del sujeto.';
            app.ContinuarButton = uibutton(app.UIFigure, 'push');
            app.ContinuarButton.ButtonPushedFcn = createCallbackFcn(app, @ContinuarButtonPushed, true);
            app.ContinuarButton.Text = 'Continuar';
            app.EscogerDatasetButton = uibutton(app.UIFigure, 'push');
            app.EscogerDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @EscogerDatasetButtonPushed, true);
            app.EscogerDatasetButton.Text = 'Escoger Dataset';
            app.DatasetEditFieldLabel = uilabel(app.UIFigure);
            app.DatasetEditFieldLabel.Enable = 'off';
            app.DatasetEditFieldLabel.Text = 'Dataset';
            app.DatasetNombre = uieditfield(app.UIFigure, 'text');
            app.DatasetNombre.Enable = 'off';
            app.FraseEditFieldLabel = uilabel(app.UIFigure);
            app.FraseEditFieldLabel.Text = 'Frase';
            app.FraseEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalGrabar

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end