classdef Final_Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DisplayRepeticion               matlab.ui.control.EditField
        RepeticinLabel                  matlab.ui.control.Label
        DisplayFrase                    matlab.ui.control.EditField
        ActividadLabel                  matlab.ui.control.Label
        DisplayGenero                   matlab.ui.control.EditField
        IdentificadorLabel_2            matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        BorrarSealesButton              matlab.ui.control.Button
        GuardarSalida                   matlab.ui.control.Button
        SealSintticaPanel               matlab.ui.container.Panel
        GenerarSealSinteticaButton      matlab.ui.control.Button
        ProcesamientoPanel              matlab.ui.container.Panel
        DominioTButton                  matlab.ui.control.Button
        FiltrosButton                   matlab.ui.control.Button
        TransformacionesButton          matlab.ui.control.Button
        SealDatasetPanel                matlab.ui.container.Panel
        CargarDataset                   matlab.ui.control.Button
        GrabarDataset                   matlab.ui.control.Button
        PreprocesamientoPanel           matlab.ui.container.Panel
        RealizarPreprocesamientoButton  matlab.ui.control.Button
        SealdeAudioPanel                matlab.ui.container.Panel
        GrabarButton                    matlab.ui.control.StateButton
        CargarAudio                     matlab.ui.control.Button
        EscucharAudio                   matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        DisplayFrecMuestreo             matlab.ui.control.EditField
        FrecuenciadeMuestreoEditFieldLabel  matlab.ui.control.Label
        DisplaySujeto                   matlab.ui.control.EditField
        IDLabel                         matlab.ui.control.Label
        axes_pre                        matlab.ui.control.UIAxes
        axes_out                        matlab.ui.control.UIAxes
        axes_in                         matlab.ui.control.UIAxes
    end


    properties (Access = public)
        grabacionAudio = audiorecorder(44100,16,2) % Description
        canalEntrada1
        canalEntrada2
        frecMuestEntrada
        senalSalida
        metadatosSujeto
        canalSalida1  % Resultado procesado del canal izquierdo
        canalSalida2
        DatosGuardados % Aquí vive el vector de la señal (EMG o Sintética)
        FsActual       % Aquí guardas la frecuencia de muestreo Señal Sintética
        DatosPreprocesados
        numCanales = 1   % 1 o 2 canales activos 
    end
    
    properties (Access = private)
       
    end
    
    methods (Access = public)
        function cargarSenalEstereoEntrada(app, senalIn, frecMuestreo)
            if size(senalIn,1) < size(senalIn,2)
                senalIn = senalIn';
            end
            N    = size(senalIn, 1);
            nCh  = size(senalIn, 2);
            t    = (0:N-1) / frecMuestreo;
            mSz  = max(1, min(4, round(300/N)));
            usarStem = (N <= 500);
        
            app.canalEntrada1 = senalIn(:,1);
            if nCh >= 2
                app.canalEntrada2 = senalIn(:,2);
                app.numCanales    = 2;
            else
                app.canalEntrada2 = [];
                app.numCanales    = 1;
            end
        
            % Limpiar NaN/Inf antes de graficar
            c1 = app.canalEntrada1; c1(~isfinite(c1)) = 0;
        
            cla(app.axes_in);
            hold(app.axes_in, 'on');
        
            if app.numCanales == 2
                c2 = app.canalEntrada2; c2(~isfinite(c2)) = 0;
                if usarStem
                    stem(app.axes_in, t, c1, 'Color',[0 0.7 0.9], 'MarkerSize',mSz, 'LineWidth',0.6);
                    stem(app.axes_in, t, c2, 'Color',[0.9 0.4 0],  'MarkerSize',mSz, 'LineWidth',0.6);
                else
                    plot(app.axes_in, t, c1, 'Color',[0 0.7 0.9], 'LineWidth',0.8);
                    plot(app.axes_in, t, c2, 'Color',[0.9 0.4 0],  'LineWidth',0.8);
                end
                legend(app.axes_in, {'Canal 1','Canal 2'}, 'TextColor','w', 'Location','northeast');
            else
                if usarStem
                    stem(app.axes_in, t, c1, 'Color',[0 0.7 0.9], 'MarkerSize',mSz, 'LineWidth',0.6);
                else
                    plot(app.axes_in, t, c1, 'Color',[0 0.7 0.9], 'LineWidth',0.8);
                end
                legend(app.axes_in, {'Canal 1'}, 'TextColor','w', 'Location','northeast');
            end
            hold(app.axes_in, 'off');
            xlabel(app.axes_in, 'Tiempo (s)');
            ylabel(app.axes_in, 'Amplitud (V)');
            grid(app.axes_in, 'on');
        
            app.DisplayFrecMuestreo.Value = string(frecMuestreo);
            app.frecMuestEntrada = frecMuestreo;
        end 

        function actualizarGraficosEntrada(app)
            if isempty(app.canalEntrada1) || isempty(app.frecMuestEntrada), return; end

            Fs  = app.frecMuestEntrada;
            x1  = app.canalEntrada1; x1(~isfinite(x1)) = 0;
            t   = (0:length(x1)-1) / Fs;
            N   = length(x1);
            mSz = max(1, min(4, round(300/N)));
            usarStem = (N <= 500);
        
            cla(app.axes_in);
            hold(app.axes_in, 'on');
        
            if app.numCanales == 2 && ~isempty(app.canalEntrada2)
                x2 = app.canalEntrada2; x2(~isfinite(x2)) = 0;
                if usarStem
                    stem(app.axes_in, t, x1, 'Color',[0 0.7 0.9], 'MarkerSize',mSz, 'LineWidth',0.6);
                    stem(app.axes_in, t, x2, 'Color',[0.9 0.4 0],  'MarkerSize',mSz, 'LineWidth',0.6);
                else
                    plot(app.axes_in, t, x1, 'Color',[0 0.7 0.9], 'LineWidth',0.8);
                    plot(app.axes_in, t, x2, 'Color',[0.9 0.4 0],  'LineWidth',0.8);
                end
                legend(app.axes_in, {'Canal 1','Canal 2'}, 'TextColor','w', 'Location','northeast');
            else
                if usarStem
                    stem(app.axes_in, t, x1, 'Color',[0 0.7 0.9], 'MarkerSize',mSz, 'LineWidth',0.6);
                else
                    plot(app.axes_in, t, x1, 'Color',[0 0.7 0.9], 'LineWidth',0.8);
                end
                legend(app.axes_in, {'Canal 1'}, 'TextColor','w', 'Location','northeast');
            end
            hold(app.axes_in, 'off');
        
            title(app.axes_in, 'Señal de Entrada', 'Color','w');
            xlabel(app.axes_in, 'Tiempo (s)');
            ylabel(app.axes_in, 'Amplitud (V)');
            grid(app.axes_in, 'on');
            app.axes_in.Color = [0 0 0];
        
            % Poblar metadatos de forma segura (evita errores si falta un campo)
            try, app.DisplaySujeto.Value       = app.metadatosSujeto.ID;              catch, end
            try, app.DisplayGenero.Value       = app.metadatosSujeto.Genero;          catch, end
            try, app.DisplayFrase.Value        = app.metadatosSujeto.Frase;           catch, end
            try, app.DisplayRepeticion.Value   = num2str(app.metadatosSujeto.Repeticion); catch, end
            try, app.DisplayFrecMuestreo.Value = num2str(Fs);                         catch, end
        end

        
        function data = readNPY(app, filename)
            % Function to read NPY files into matlab.
            % Recibe 'app' para poder llamar a readNPYheader como función privada.
            
            [shape, dataType, fortranOrder, littleEndian, totalHeaderLength, ~] = app.readNPYheader(filename);
            
            if littleEndian
                fid = fopen(filename, 'r', 'l');
            else
                fid = fopen(filename, 'r', 'b');
            end
            
            try
                [~] = fread(fid, totalHeaderLength, 'uint8');
                % read the data
                data = fread(fid, prod(shape), [dataType '=>' dataType]);
                
                if length(shape)>1 && ~fortranOrder
                    data = reshape(data, shape(end:-1:1));
                    data = permute(data, [length(shape):-1:1]);
                elseif length(shape)>1
                    data = reshape(data, shape);
                end
                fclose(fid);
            catch me
                if fid ~= -1, fclose(fid); end
                rethrow(me);
            end
        end
                
                function [arrayShape, dataType, fortranOrder, littleEndian, totalHeaderLength, npyVersion] = readNPYheader(app, filename)
            % parse the header of a .npy file and return all the info contained
            % Recibe 'app' como primer argumento para cumplir con el estándar de App Designer.
            
            fid = fopen(filename);
            % verify that the file exists
            if (fid == -1)
                if ~isempty(dir(filename))
                    error('Permission denied: %s', filename);
                else
                    error('File not found: %s', filename);
                end
            end
            
            try
                dtypesMatlab = {'uint8','uint16','uint32','uint64','int8','int16','int32','int64','single','double', 'logical'};
                dtypesNPY = {'u1', 'u2', 'u4', 'u8', 'i1', 'i2', 'i4', 'i8', 'f4', 'f8', 'b1'};
                
                magicString = fread(fid, [1 6], 'uint8=>uint8');
                
                if ~all(magicString == [147,78,85,77,80,89])
                    error('readNPY:NotNUMPYFile', 'Error: El archivo no parece tener formato NUMPY.');
                end
                
                majorVersion = fread(fid, [1 1], 'uint8=>uint8');
                minorVersion = fread(fid, [1 1], 'uint8=>uint8');
                npyVersion = [majorVersion minorVersion];
                
                headerLength = fread(fid, [1 1], 'uint16=>uint16');
                totalHeaderLength = 10+headerLength;
                
                arrayFormat = fread(fid, [1 headerLength], 'char=>char');
                
                % Parseo de descripción de datos
                r = regexp(arrayFormat, '''descr''\s*:\s*''(.*?)''', 'tokens');
                if isempty(r), error('No se pudo parsear el formato: "%s"', arrayFormat); end
                dtNPY = r{1}{1};    
                
                littleEndian = ~strcmp(dtNPY(1), '>');
                dataType = dtypesMatlab{strcmp(dtNPY(2:3), dtypesNPY)};
                    
                % Parseo de orden (Fortran/C)
                r = regexp(arrayFormat, '''fortran_order''\s*:\s*(\w+)', 'tokens');
                fortranOrder = strcmp(r{1}{1}, 'True');
                
                % Parseo de dimensiones (Shape)
                r = regexp(arrayFormat, '''shape''\s*:\s*\((.*?)\)', 'tokens');
                shapeStr = r{1}{1}; 
                arrayShape = str2num(shapeStr(shapeStr~='L'));
                
                fclose(fid);
                
            catch me
                if fid ~= -1, fclose(fid); end
                rethrow(me);
            end
                end
        end

    
    methods (Access = private)
        
        function meta = leerMetadatosCompanero(app, path, nombre)
            meta = struct('ID','','Genero','','Frase','','Repeticion',0,'Fs',0);
            candidatos = { ...
                fullfile(path, [nombre '_meta.xlsx']), ...
                fullfile(path, [nombre '_meta.csv']),  ...
                fullfile(path, 'metadata.xlsx'),       ...
                fullfile(path, 'metadata.csv')};
        
            archivoMeta = '';
            for i = 1:numel(candidatos)
                if isfile(candidatos{i})
                    archivoMeta = candidatos{i};
                    break;
                end
            end
            if isempty(archivoMeta), return; end
        
            try
                T = readtable(archivoMeta, 'TextType', 'string');
                colsLow = lower(T.Properties.VariableNames);
        
                if ismember('id', colsLow) || ismember('idsujeto', colsLow)
                    % Formato columnar (una fila por registro)
                    idxID  = find(ismember(colsLow, {'id','idsujeto','sujeto','subject'}), 1);
                    idxGen = find(ismember(colsLow, {'genero','gender','sexo'}), 1);
                    idxFra = find(ismember(colsLow, {'frase','actividad','activity','label','palabra'}), 1);
                    idxRep = find(ismember(colsLow, {'repeticion','rep','repeat','trial'}), 1);
                    idxFs  = find(ismember(colsLow, {'fs','frecuencia','samplerate','freq','sr'}), 1);
        
                    if ~isempty(idxID),  meta.ID         = char(T{1,idxID});          end
                    if ~isempty(idxGen), meta.Genero     = char(T{1,idxGen});         end
                    if ~isempty(idxFra), meta.Frase      = char(T{1,idxFra});         end
                    if ~isempty(idxRep), meta.Repeticion = double(T{1,idxRep});       end
                    if ~isempty(idxFs),  meta.Fs         = double(T{1,idxFs});        end
        
                elseif width(T) == 2
                    % Formato clave-valor
                    for r = 1:height(T)
                        cLow = lower(char(T{r,1}));
                        val  = char(T{r,2});
                        switch cLow
                            case {'id','idsujeto','sujeto','subject'}
                                meta.ID         = val;
                            case {'genero','gender','sexo'}
                                meta.Genero     = val;
                            case {'frase','actividad','activity','label','palabra'}
                                meta.Frase      = val;
                            case {'repeticion','rep','repeat','trial'}
                                meta.Repeticion = str2double(val);
                            case {'fs','frecuencia','samplerate','freq','sr'}
                                meta.Fs         = str2double(val);
                        end
                    end
                end
            catch
                % Si no se puede leer, continúa sin metadatos
            end
                end
        
        function metaOut = completarMetadatos(app, meta)
            metaOut = meta;
        
            faltaID  = isempty(meta.ID);
            faltaGen = isempty(meta.Genero);
            faltaFra = isempty(meta.Frase);
            faltaRep = (meta.Repeticion <= 0 || isnan(meta.Repeticion));
            faltaFs  = (meta.Fs <= 0 || isnan(meta.Fs));
        
            if ~faltaID && ~faltaGen && ~faltaFra && ~faltaRep && ~faltaFs
                return;   % Todos completos, no preguntar nada
            end
        
            prompts  = {};
            defaults = {};
            campos   = {};
        
            if faltaID,  prompts{end+1} = 'ID Sujeto:';              defaults{end+1} = 'S01';  campos{end+1} = 'ID';         end
            if faltaGen, prompts{end+1} = 'Género (Masculino/Femenino):'; defaults{end+1} = 'Masculino'; campos{end+1} = 'Genero'; end
            if faltaFra, prompts{end+1} = 'Frase / Palabra:';        defaults{end+1} = '';     campos{end+1} = 'Frase';      end
            if faltaRep, prompts{end+1} = 'Número de Repetición:';   defaults{end+1} = '1';    campos{end+1} = 'Repeticion'; end
            if faltaFs,  prompts{end+1} = 'Frecuencia de Muestreo (Hz):'; defaults{end+1} = '1000'; campos{end+1} = 'Fs';   end
        
            resp = inputdlg(prompts, 'Metadatos faltantes', [1 55], defaults);
            if isempty(resp)
                metaOut = [];
                return;
            end
        
            for i = 1:numel(campos)
                switch campos{i}
                    case {'Repeticion','Fs'}
                        metaOut.(campos{i}) = str2double(resp{i});
                    otherwise
                        metaOut.(campos{i}) = resp{i};
                end
            end
        end
        
        function capturarDatosDesdeHardware(app)
        % 1. Configuración Hardware
        port = "COM6"; 
        baudRate = 115200;
        try
            s = serialport(port, baudRate);
            flush(s);
        catch
            mensaje = "No se pudo conectar al puerto: " + port; 
            uialert(app.UIFigure, mensaje, 'Error de Hardware');
            return;
        end

        % 2. Parámetros (Basados en tu código original)
        Fs = 1000;
        hablar = 3500;   % muestras
        silencio = 1500; % muestras
        N = (2*silencio) + hablar; 
        
        data = zeros(2, N);
        i = 1;
        app.Lamp.Color = 'green'; % Indicador visual de grabando
        
        % 3. Bucle de Captura
        while i < N+1
            if s.NumBytesAvailable > 0
                raw = readline(s);
                twovalue = split(raw);
                v1 = str2double(twovalue(1)); v2 = str2double(twovalue(2));
                if ~isnan(v1) && ~isnan(v2)
                    data(1,i) = v1; data(2,i) = v2;
                    i = i + 1;
                end
            end
        end
        app.Lamp.Color = 'red'; % Termino la captura
        delete(s); % Liberar puerto
        
        % 4. Guardar en propiedades de la App
        app.canalEntrada1 = data(1,:)';
        app.canalEntrada2 = data(2,:)';
        app.frecMuestEntrada = Fs;
        
        % Graficar en axes_in
        t = (0:N-1)/Fs;
        plot(app.axes_in, t, data(1,:), t, data(2,:));
        title(app.axes_in, 'Señal Recién Capturada');
        
        uialert(app.UIFigure, 'Captura finalizada con éxito.', 'Éxito');
    end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: GrabarButton
        function GrabarButtonValueChanged(app, event)
            if app.GrabarButton.Value == 1
        % REASIGNAR EL OBJETO AQUÍ ES LA CLAVE
                app.grabacionAudio = audiorecorder(44100, 16, 2); 
        
                app.Lamp.Color = 'green';
                app.Lamp.Enable = 'on';
        
                % Iniciar grabación
                record(app.grabacionAudio);
            else
                % Detener grabación
                stop(app.grabacionAudio);
                app.Lamp.Color = [0.15 0.15 0.15]; % Color gris oscuro
            end
        end

        % Button pushed function: EscucharAudio
        function EscucharAudioButtonPushed(app, event)
            % Obtenemos los datos numéricos de la señal que ya viste en la gráfica
    y = getaudiodata(app.grabacionAudio);
    fs = 44100; % La frecuencia que definiste
    
    if ~isempty(y)
        % sound es una función básica de MATLAB que suele ser más directa
        sound(y, fs); 
    else
        uialert(app.UIFigure, 'No hay datos de audio', 'Aviso');
    end
        end

        % Button pushed function: CargarAudio
        function CargarAudioButtonPushed(app, event)
           datosRaw = getaudiodata(app.grabacionAudio);
            fs = 44100;
        
            if isempty(datosRaw)
                uialert(app.UIFigure, 'No hay audio grabado.', 'Aviso');
                return;
            end
        
            app.DatosGuardados = datosRaw;
            app.FsActual       = fs;
            app.numCanales     = min(size(datosRaw, 2), 2);
        
            % ── Metadatos para señal de audio ──
            app.metadatosSujeto.ID         = 'Audio';
            app.metadatosSujeto.Genero     = 'N/A';
            app.metadatosSujeto.Frase      = 'N/A';
            app.metadatosSujeto.Repeticion = 0;
        
            app.DisplaySujeto.Value       = 'Audio';
            app.DisplayGenero.Value       = 'N/A';
            app.DisplayFrase.Value        = 'N/A';
            app.DisplayRepeticion.Value   = 'N/A';
            app.DisplayFrecMuestreo.Value = num2str(fs);
        
            cargarSenalEstereoEntrada(app, datosRaw, fs);
            title(app.axes_in, 'Entrada de Audio Real', 'Color', 'w');
        end

        % Callback function
        function TransformacionDropDownValueChanged(app, event)
            value = app.TransformacionDropDown.Value;
    if isempty(app.canalEntrada1)
        uialert(app.UIFigure, 'Cargue una señal primero', 'Error');
        return; 
    end
    
    Fs = app.frecMuestEntrada; 
    x = app.canalEntrada1; 
    N = length(x);
    t = (0:N-1)/Fs;

    % Crear figura externa con fondo oscuro para mejor visibilidad
    figSalida = figure('Name', ['Análisis de ' char(value)], 'NumberTitle', 'off', 'Color', [0.1 0.1 0.1]);

    switch value
        case "Fourier"
                nfft = 2^nextpow2(N);
                X1   = abs(fft(x1, nfft));
                X2   = abs(fft(x2, nfft));
                f    = (0:nfft/2) * (Fs/nfft);
                mag1 = X1(1:nfft/2+1) / N;
                mag2 = X2(1:nfft/2+1) / N;
                mag1(2:end-1) = 2*mag1(2:end-1);
                mag2(2:end-1) = 2*mag2(2:end-1);
        
                ax3 = subplot(3,2,[3,4,5,6]);
                hold(ax3,'on');
                plot(ax3, f, mag1, 'Color', [0 0.7 0.9], 'LineWidth', 1);
                plot(ax3, f, mag2, 'Color', [0.9 0.4 0],  'LineWidth', 1);
                hold(ax3,'off');
                title(ax3, 'Espectro de Magnitud DFT (Cian: C1, Naranja: C2)', 'Color', 'w');
                xlabel(ax3, 'Frecuencia (Hz)'); ylabel(ax3, 'Amplitud');
                legend(ax3, {'Canal 1','Canal 2'}, 'TextColor','w','Color','none');
                set(ax3, 'Color','k','XColor','w','YColor','w'); grid(ax3,'on');
        
                cla(app.axes_out);
                hold(app.axes_out,'on');
                plot(app.axes_out, f, mag1, 'Color', [0 0.7 0.9], 'LineWidth', 1);
                plot(app.axes_out, f, mag2, 'Color', [0.9 0.4 0],  'LineWidth', 1);
                hold(app.axes_out,'off');
                title(app.axes_out, 'Salida Fourier: Espectro de Magnitud DFT', 'Color', 'w');
                xlabel(app.axes_out, 'Frecuencia (Hz)'); ylabel(app.axes_out, 'Amplitud');
                grid(app.axes_out,'on');
                
                % ---- AJUSTE DE ZOOM AUTOMÁTICO ----
                app.axes_out.XLimMode = 'auto';
                app.axes_out.YLimMode = 'auto';
        
                % OJO: NO guardamos la magnitud en canalSalida1 para no corromper el dominio del tiempo.
                % Solo visualizamos. Si necesitas guardar el espectro después, crea app.espectroSalida1.

        case "Coseno"
            % --- 1.3.6: DCT y Filtrado Ideal [cite: 56, 57, 58] ---
            y_dct = dct(x);
            corte = round(0.15 * N);
            y_dct_filtrada = zeros(size(y_dct));
            y_dct_filtrada(1:corte) = y_dct(1:corte);
            x_rec = idct(y_dct_filtrada);

            % Magnitud DCT
            ax1 = subplot(2,1,1, 'Parent', figSalida);
            plot(ax1, abs(y_dct), 'Color', [1 0.5 0]); hold(ax1, 'on');
            plot(ax1, abs(y_dct_filtrada), 'Color', 'g', 'LineWidth', 1); hold(ax1, 'off');
            title(ax1, 'Magnitud DCT: Original vs Filtro Ideal', 'Color', 'w');
            xlabel(ax1, 'Índice de Coeficiente'); ylabel(ax1, 'Amplitud');
            legend(ax1, 'Original', 'Filtrada', 'TextColor', 'w', 'Color', 'none');
            set(ax1, 'Color', 'k', 'XColor', 'w', 'YColor', 'w');

            % Señal Reconstruida
            ax2 = subplot(2,1,2, 'Parent', figSalida);
            plot(ax2, t, x, 'Color', [0.5 0.5 0.5]); hold(ax2, 'on');
            plot(ax2, t, x_rec, 'Color', 'w', 'LineWidth', 1.2); hold(ax2, 'off');
            title(ax2, 'Señal de Entrada vs Salida Filtrada (IDCT)', 'Color', 'w');
            xlabel(ax2, 'Tiempo (s)'); ylabel(ax2, 'Amplitud');
            set(ax2, 'Color', 'k', 'XColor', 'w', 'YColor', 'w');

        case "Wavelet"
            % --- 1.3.7: Wavelet db6 a 3 niveles [cite: 60, 61, 63] ---
            [c, l] = wavedec(x, 3, 'db6');
            A3 = appcoef(c, l, 'db6', 3);
            [D1, D2, D3] = detcoef(c, l, [1 2 3]);

            titulos = {'Aproximación A3 (Baja Frec)', 'Detalle D3', 'Detalle D2', 'Detalle D1 (Alta Frec)'};
            datos = {A3, D3, D2, D1};
            colores = {'c', 'g', 'y', 'r'};
            
            for i = 1:4
                ax = subplot(4,1,i, 'Parent', figSalida);
                plot(ax, datos{i}, colores{i});
                title(ax, titulos{i}, 'Color', 'w');
                xlabel(ax, 'Muestras'); ylabel(ax, 'Amplitud');
                set(ax, 'Color', 'k', 'XColor', 'w', 'YColor', 'w');
            end
    end
        end

        % Callback function
        function CargarSinteticaButtonPushed(app, event)
            A = app.SintAmplitud.Value;
            Tfull = app.SintDuracion.Value;
            Fa = app.SintFrecAnal.Value;
            Ff = app.SintFrecFinal.Value;
            Fs = app.SintFrecMuestreo.Value;
            out = [];

            t = 0:(1/Fs):Tfull;
            switch app.TipoDropDown.Value
                case "Seno"
                    out = A*sin(2*pi*Fa*t);
                    app.DisplaySujeto.Value = "Seno";
                case "Coseno"
                    out = A*cos(2*pi*Fa*t);
                    app.DisplaySujeto.Value = "Coseno";
                case "Sinc"
                    out = A*sinc(2*pi*Fa*t);
                    app.DisplaySujeto.Value = "Seno Cardinal";
                case "Chirp"
                    out = A*chirp(t,Fa,Tfull,Ff);
                    app.DisplaySujeto.Value = "Chirp";
            end
            cargarSenalEstereoEntrada(app, [out(:), out(:)], Fs);
        end

        % Callback function
        function FiltroDropDownValueChanged(app, event)
            if isempty(app.canalEntrada1)
                uialert(app.UIFigure, 'Cargue una señal primero', 'Error');
            return; 
            end

            % Aseguramos que la entrada sea un vector fila o columna simple
            x = app.canalEntrada1(:); 
            Fs = app.frecMuestEntrada;
            t = (0:length(x)-1)/Fs;
            
            
            origen = uiconfirm(app.UIFigure, '¿Cómo desea ingresar los parámetros?', ...
                'Configuración del Sistema', 'Options', {'Ventana de Diálogo', 'Desde Archivo'});
        
            if strcmp(origen, 'Desde Archivo')
                [file, path] = uigetfile('*.txt', 'Seleccione el archivo de coeficientes');
                if isequal(file, 0), return; end
                data = importdata(fullfile(path, file));
                % Si es FIR, el archivo debe tener una fila. Si es IIR, dos.
                if isstruct(data), coeffs_file = data.data; else, coeffs_file = data; end
            end
        
            tipoFiltro = uiconfirm(app.UIFigure, 'Seleccione el tipo de sistema:', ...
                'Procesamiento 1.3.3', 'Options', {'Sistema FIR', 'Sistema IIR'});
        
            switch tipoFiltro
                case 'Sistema FIR'
                    if strcmp(origen, 'Ventana de Diálogo')
                        promptH = {'Ingrese los coeficientes h(n) (separados por espacio):'};
                        h_str = inputdlg(promptH, 'Parámetros h(n)', [1 70], {'0.2 0.5 0.2'});
                        if isempty(h_str), return; end
                        h = str2num(h_str{1}); 
                    else
                        h = coeffs_file(1, :); % Toma la primera fila del archivo
                    end
                    
                    
                    h = h(:); 
                    
                    % Implementación FIR (y(n) = sum x(n-k)h(k)) 
                    y = conv(x, h, 'same'); 
                    metodo = 'FIR (Convolucion)';
        
                case 'Sistema IIR'
                    if strcmp(origen, 'Ventana de Diálogo')
                        promptIIR = {'Coeficientes b_k (numerador):', 'Coeficientes a_k (denominador):'};
                        coeffs = inputdlg(promptIIR, 'Ecuación de Diferencia', [1 70; 1 70], {'1', '1 -0.5'});
                        if isempty(coeffs), return; end
                        b = str2num(coeffs{1}); a = str2num(coeffs{2});
                    else
                        b = coeffs_file(1, :); a = coeffs_file(2, :);
                    end
        
                    % Implementación IIR (Ecuación de diferencia) [cite: 169]
                    y = filter(b, a, x);
                    metodo = 'IIR (Ecuacion de Diferencia)';
            end
        
            app.canalSalida1 = y; % Guardar resultado en la propiedad de la App
        
            
            figFiltro = figure('Name', metodo, 'NumberTitle', 'off', 'Color', [0.1 0.1 0.1]);
            ax1 = subplot(2,1,1, 'Parent', figFiltro);
            plot(ax1, t, x, 'Color', 'cyan'); title(ax1, 'Entrada', 'Color', 'w');
            xlabel(ax1, 'Tiempo (s)'); ylabel(ax1, 'Amplitud');
            
            ax2 = subplot(2,1,2, 'Parent', figFiltro);
            plot(ax2, t, y, 'Color', 'g'); title(ax2, ['Salida ' metodo], 'Color', 'w');
            xlabel(ax2, 'Tiempo (s)'); ylabel(ax2, 'Amplitud');
            set([ax1, ax2], 'Color', 'k', 'XColor', 'w', 'YColor', 'w');
        end

        % Button pushed function: TransformacionesButton
        function TransformacionesButtonPushed(app, event)
            if isempty(app.DatosPreprocesados)
            uialert(app.UIFigure, 'Realice el preprocesamiento primero.', 'Error');
            return;
            end
        
        seleccion = uiconfirm(app.UIFigure, 'Transformación:', 'Análisis', 'Options', {'Fourier', 'Coseno', 'Wavelet'});
        Fs = app.FsActual;
        x1 = app.DatosPreprocesados(:,1);
        x2 = app.DatosPreprocesados(:,2);
        N  = length(x1);
        t  = (0:N-1) / Fs;
        
        % Offset visual para separar canales en las entradas
        offset_in = max(abs(x1)) + max(abs(x2));
        
        figT = figure('Name', ['Análisis: ' seleccion], 'Color', [0.1 0.1 0.1]);
        
        % ── Paneles de entrada ──
        ax1 = subplot(3,2,1);
        plot(ax1, t, x1, 'Color', [0 0.7 0.9], 'LineWidth', 0.8);
        title(ax1, 'Entrada Preprocesada C1', 'Color', 'w');
        xlabel(ax1, 'Tiempo (s)'); ylabel(ax1, 'Amplitud');
        
        ax2 = subplot(3,2,2);
        plot(ax2, t, x2, 'Color', [0.9 0.4 0], 'LineWidth', 0.8);
        title(ax2, 'Entrada Preprocesada C2', 'Color', 'w');
        xlabel(ax2, 'Tiempo (s)'); ylabel(ax2, 'Amplitud');
        
        set([ax1, ax2], 'Color', 'k', 'XColor', 'w', 'YColor', 'w');
        grid(ax1,'on'); grid(ax2,'on');
        
        switch seleccion
        
            case "Fourier"
                nfft = 2^nextpow2(N);
                X1   = abs(fft(x1, nfft));
                X2   = abs(fft(x2, nfft));
                f    = (0:nfft/2) * (Fs/nfft);
                mag1 = X1(1:nfft/2+1) / N;
                mag2 = X2(1:nfft/2+1) / N;
                mag1(2:end-1) = 2*mag1(2:end-1);
                mag2(2:end-1) = 2*mag2(2:end-1);
        
                ax3 = subplot(3,2,[3,4,5,6]);
                hold(ax3,'on');
                plot(ax3, f, mag1, 'Color', [0 0.7 0.9], 'LineWidth', 1);
                plot(ax3, f, mag2, 'Color', [0.9 0.4 0],  'LineWidth', 1);
                hold(ax3,'off');
                title(ax3, 'Espectro de Magnitud DFT (Cian: C1, Naranja: C2)', 'Color', 'w');
                xlabel(ax3, 'Frecuencia (Hz)'); ylabel(ax3, 'Amplitud');
                legend(ax3, {'Canal 1','Canal 2'}, 'TextColor','w','Color','none');
                set(ax3, 'Color','k','XColor','w','YColor','w'); grid(ax3,'on');
        
                cla(app.axes_out);
                hold(app.axes_out,'on');
                plot(app.axes_out, f, mag1, 'Color', [0 0.7 0.9], 'LineWidth', 1);
                plot(app.axes_out, f, mag2, 'Color', [0.9 0.4 0],  'LineWidth', 1);
                hold(app.axes_out,'off');
                title(app.axes_out, 'Salida Fourier: Espectro de Magnitud DFT', 'Color', 'w');
                xlabel(app.axes_out, 'Frecuencia (Hz)'); ylabel(app.axes_out, 'Amplitud');
                grid(app.axes_out,'on');
        
                app.canalSalida1 = mag1(:);
                app.canalSalida2 = mag2(:);
        
            case "Coseno"
                y_dct1 = dct(x1);
                y_dct2 = dct(x2);
                corte  = round(0.15 * N);
                y_dct1_f = zeros(size(y_dct1)); y_dct1_f(1:corte) = y_dct1(1:corte);
                y_dct2_f = zeros(size(y_dct2)); y_dct2_f(1:corte) = y_dct2(1:corte);
                x_rec1 = idct(y_dct1_f);
                x_rec2 = idct(y_dct2_f);
                offset_rec = max(abs(x_rec1)) + max(abs(x_rec2));
        
                ax3 = subplot(3,2,[3,4]);
                hold(ax3,'on');
                plot(ax3, abs(y_dct1),   'Color', [0 0.7 0.9], 'LineWidth', 1);
                plot(ax3, abs(y_dct1_f), 'Color', [0.4 1 0.4], 'LineWidth', 1);
                hold(ax3,'off');
                title(ax3, 'DCT C1: Original vs Filtrada', 'Color', 'w');
                xlabel(ax3, 'Índice'); ylabel(ax3, 'Amplitud');
                legend(ax3, {'Original','Filtrada'}, 'TextColor','w','Color','none');
                set(ax3, 'Color','k','XColor','w','YColor','w'); grid(ax3,'on');
        
                ax4 = subplot(3,2,[5,6]);
                hold(ax4,'on');
                plot(ax4, t, x_rec1 - offset_rec/2, 'Color', [0 0.7 0.9], 'LineWidth', 0.8);
                plot(ax4, t, x_rec2 + offset_rec/2, 'Color', [0.9 0.4 0],  'LineWidth', 0.8);
                hold(ax4,'off');
                title(ax4, 'Reconstruida IDCT (Cian: C1 ↓, Naranja: C2 ↑)', 'Color', 'w');
                xlabel(ax4, 'Tiempo (s)'); ylabel(ax4, 'Amplitud');
                set(ax4, 'Color','k','XColor','w','YColor','w'); grid(ax4,'on');
        
                cla(app.axes_out);
                hold(app.axes_out,'on');
                plot(app.axes_out, t, x_rec1 - offset_rec/2, 'Color', [0 0.7 0.9], 'LineWidth', 0.8);
                plot(app.axes_out, t, x_rec2 + offset_rec/2, 'Color', [0.9 0.4 0],  'LineWidth', 0.8);
                hold(app.axes_out,'off');
                title(app.axes_out, 'Salida Coseno: IDCT (Cian: C1 ↓, Naranja: C2 ↑)', 'Color', 'w');
                xlabel(app.axes_out, 'Tiempo (s)'); ylabel(app.axes_out, 'Amplitud');
                grid(app.axes_out,'on');
        
                app.canalSalida1       = x_rec1;
                app.canalSalida2       = x_rec2;
                app.canalEntrada1      = x_rec1;
                app.canalEntrada2      = x_rec2;
                app.DatosPreprocesados = [x_rec1(:), x_rec2(:)];
        
            case "Wavelet"
                [c1, l1] = wavedec(x1, 3, 'db6');
                [c2, l2] = wavedec(x2, 3, 'db6');
                A3_c1 = appcoef(c1, l1, 'db6', 3);
                A3_c2 = appcoef(c2, l2, 'db6', 3);
                rec1  = wrcoef('a', c1, l1, 'db6', 3);
                rec2  = wrcoef('a', c2, l2, 'db6', 3);
                offset_rec = max(abs(rec1)) + max(abs(rec2));
        
                ax3 = subplot(3,2,[3,5]);
                plot(ax3, A3_c1, 'Color', [0 0.7 0.9], 'LineWidth', 0.8);
                title(ax3, 'Aproximación A3 (C1)', 'Color', 'w');
                xlabel(ax3, 'Muestras'); ylabel(ax3, 'Amplitud');
                set(ax3, 'Color','k','XColor','w','YColor','w'); grid(ax3,'on');
        
                ax4 = subplot(3,2,[4,6]);
                plot(ax4, A3_c2, 'Color', [0.9 0.4 0], 'LineWidth', 0.8);
                title(ax4, 'Aproximación A3 (C2)', 'Color', 'w');
                xlabel(ax4, 'Muestras'); ylabel(ax4, 'Amplitud');
                set(ax4, 'Color','k','XColor','w','YColor','w'); grid(ax4,'on');
        
                cla(app.axes_out);
                hold(app.axes_out,'on');
                plot(app.axes_out, t, rec1 - offset_rec/2, 'Color', [0 0.7 0.9], 'LineWidth', 0.8);
                plot(app.axes_out, t, rec2 + offset_rec/2, 'Color', [0.9 0.4 0],  'LineWidth', 0.8);
                hold(app.axes_out,'off');
                title(app.axes_out, 'Salida Wavelet: Reconstr. A3 db6 (Cian: C1 ↓, Naranja: C2 ↑)', 'Color', 'w');
                xlabel(app.axes_out, 'Tiempo (s)'); ylabel(app.axes_out, 'Amplitud');
                grid(app.axes_out,'on');
        
                app.canalSalida1       = rec1;
                app.canalSalida2       = rec2;
                app.canalEntrada1      = rec1;
                app.canalEntrada2      = rec2;
                app.DatosPreprocesados = [rec1(:), rec2(:)];
        end 
        end

        % Button pushed function: FiltrosButton
        function FiltrosButtonPushed(app, event)
            if isempty(app.DatosPreprocesados)
            uialert(app.UIFigure, 'Realice el preprocesamiento primero.', 'Error');
            return;
        end
        
        Fs = app.FsActual;
        x1 = app.DatosPreprocesados(:,1);
        x2 = app.DatosPreprocesados(:,2);
        t  = (0:length(x1)-1) / Fs;
        
        origen     = uiconfirm(app.UIFigure, '¿Origen de parámetros?', 'Configuración', 'Options', {'Ventana', 'Archivo'});
        tipoFiltro = uiconfirm(app.UIFigure, 'Tipo de sistema:', 'Filtros', 'Options', {'Sistema FIR', 'Sistema IIR'});
        
        if strcmp(tipoFiltro, 'Sistema FIR')
            if strcmp(origen, 'Ventana')
                h_str = inputdlg('Coeficientes h(n):', 'FIR', [1 60], {'0.2 0.5 0.2'});
                if isempty(h_str), return; end
                h = str2num(h_str{1}); %#ok<ST2NM>
            else
                [file, path] = uigetfile('*.txt');
                if isequal(file,0), return; end
                h = importdata(fullfile(path, file));
            end
            y1 = conv(x1, h(:), 'same');
            y2 = conv(x2, h(:), 'same');
            metodo = 'FIR (Convolución)';
        else
            if strcmp(origen, 'Ventana')
                coeffs = inputdlg({'Numerador (b):', 'Denominador (a):'}, 'IIR', [1 60; 1 60], {'1', '1 -0.5'});
                if isempty(coeffs), return; end
                b = str2num(coeffs{1}); %#ok<ST2NM>
                a = str2num(coeffs{2}); %#ok<ST2NM>
            else
                [file, path] = uigetfile('*.txt');
                if isequal(file,0), return; end
                data = importdata(fullfile(path, file));
                b = data(1,:); a = data(2,:);
            end
            % Verificar denominador para evitar división por cero
            if a(1) == 0
                uialert(app.UIFigure, 'El coeficiente a(1) no puede ser cero.', 'Error');
                return;
            end
            y1 = filter(b, a, x1);
            y2 = filter(b, a, x2);
            metodo = 'IIR (Ec. Diferencia)';
        end
        
        % ── Offset visual igual al que usa axes_pre ──
        offset_in  = max(abs(x1)) + max(abs(x2));
        offset_out = max(abs(y1)) + max(abs(y2));
        
        figF = figure('Name', ['Resultado: ' metodo], 'Color', [0.1 0.1 0.1]);
        
        % Panel entrada Canal 1
        axIn1 = subplot(2,2,1, 'Parent', figF);
        plot(axIn1, t, x1, 'Color', [0 0.7 0.9], 'LineWidth', 0.8);
        title(axIn1, 'Entrada Preprocesada: Canal 1', 'Color', 'w');
        xlabel(axIn1, 'Tiempo (s)'); ylabel(axIn1, 'Amplitud');
        
        % Panel entrada Canal 2
        axIn2 = subplot(2,2,2, 'Parent', figF);
        plot(axIn2, t, x2, 'Color', [0.9 0.4 0], 'LineWidth', 0.8);
        title(axIn2, 'Entrada Preprocesada: Canal 2', 'Color', 'w');
        xlabel(axIn2, 'Tiempo (s)'); ylabel(axIn2, 'Amplitud');
        
        % Panel salida — canales separados visualmente
        axOut = subplot(2,2,[3,4], 'Parent', figF);
        hold(axOut, 'on');
        plot(axOut, t, y1 - offset_out/2, 'Color', [0 0.7 0.9], 'LineWidth', 0.8);
        plot(axOut, t, y2 + offset_out/2, 'Color', [0.9 0.4 0],  'LineWidth', 0.8);
        hold(axOut, 'off');
        title(axOut, ['Salida ' metodo ' (Cian: C1 ↓, Naranja: C2 ↑)'], 'Color', 'w');
        xlabel(axOut, 'Tiempo (s)'); ylabel(axOut, 'Amplitud');
        legend(axOut, {'Canal 1','Canal 2'}, 'TextColor','w','Color','none');
        
        set([axIn1, axIn2, axOut], 'Color', 'k', 'XColor', 'w', 'YColor', 'w');
        grid(axIn1,'on'); grid(axIn2,'on'); grid(axOut,'on');
        
        % axes_out de la app — misma separación
        cla(app.axes_out);
        hold(app.axes_out, 'on');
        plot(app.axes_out, t, y1 - offset_out/2, 'Color', [0 0.7 0.9], 'LineWidth', 0.8);
        plot(app.axes_out, t, y2 + offset_out/2, 'Color', [0.9 0.4 0],  'LineWidth', 0.8);
        hold(app.axes_out, 'off');
        title(app.axes_out, ['Salida: ' metodo ' (Cian: C1 ↓, Naranja: C2 ↑)'], 'Color', 'w');
        xlabel(app.axes_out, 'Tiempo (s)'); ylabel(app.axes_out, 'Amplitud');
        grid(app.axes_out, 'on');
        
        app.canalSalida1       = y1;
        app.canalSalida2       = y2;
        app.canalEntrada1      = y1;
        app.canalEntrada2      = y2;
        app.DatosPreprocesados = [y1(:), y2(:)];
        end

        % Button pushed function: CargarDataset
        function CargarDatasetButtonPushed(app, event)
        filtros = {'*.mat;*.csv;*.npy', 'Todos los Datasets (*.mat, *.csv, *.npy)'};
            [file, path] = uigetfile(filtros, 'Seleccionar Dataset');
            if isequal(file, 0), return; end
            fullPath = fullfile(path, file);
            [~, nombre, ext] = fileparts(file);
        
            try
                meta          = struct('ID','','Genero','','Frase','','Repeticion',0,'Fs',0);
                rawSignal     = [];
                camposLectura = {};
                registroBase  = [];
        
                if strcmp(ext, '.mat')
                    dataStruct = load(fullPath);
                    topCampos  = fieldnames(dataStruct);
                    primeraVar = dataStruct.(topCampos{1});
        
                    % ── ¿El .mat contiene un struct array (ej. dataset 1×60)? ──────
                    if isstruct(primeraVar) && numel(primeraVar) > 1
                        n             = numel(primeraVar);
                        camposLectura = fieldnames(primeraVar);
                        resp = inputdlg( ...
                            {['Índice del registro a cargar (1 – ' num2str(n) '):']}, ...
                            'Seleccionar Registro', [1 50], {'1'});
                        if isempty(resp), return; end
                        idx          = max(1, min(n, round(str2double(resp{1}))));
                        registroBase = primeraVar(idx);
                    else
                        % Variables individuales en el .mat
                        registroBase  = dataStruct;
                        camposLectura = topCampos;
                    end
        
                    % ── Buscar señal: primer campo numérico con más de 1 elemento ──
                    for i = 1:numel(camposLectura)
                        v = registroBase.(camposLectura{i});
                        if isnumeric(v) && numel(v) > 1
                            rawSignal = double(v);
                            break;
                        end
                    end
        
                    % ── Leer metadatos ──────────────────────────────────────────────
                    for i = 1:numel(camposLectura)
                        cLow = lower(camposLectura{i});
                        val  = registroBase.(camposLectura{i});
                        try
                            switch cLow
                                case {'idsujeto','id','sujeto','subject','paciente'}
                                    meta.ID         = strtrim(char(val));
                                case {'genero','gender','sexo','sex'}
                                    meta.Genero     = strtrim(char(val));
                                case {'frase','actividad','activity','label','movimiento','palabra','word'}
                                    meta.Frase      = strtrim(char(val));
                                case {'repeticion','rep','repeat','trial'}
                                    if isnumeric(val) && isscalar(val)
                                        meta.Repeticion = double(val);
                                    end
                                case {'fs','frecuencia','samplerate','sampling_rate','freq','sr'}
                                    if isnumeric(val) && isscalar(val)
                                        meta.Fs = double(val);
                                    end
                            end
                        catch
                        end
                    end
        
                elseif strcmp(ext, '.csv')
                    rawSignal = readmatrix(fullPath);
                    meta      = app.leerMetadatosCompanero(path, nombre);
        
                elseif strcmp(ext, '.npy')
                    rawSignal = app.readNPY(fullPath);
                    meta      = app.leerMetadatosCompanero(path, nombre);
                end
        
                % ── Validar señal ───────────────────────────────────────────────────
                if isempty(rawSignal)
                    uialert(app.UIFigure, ...
                        'No se encontró una señal numérica válida en el archivo.', 'Error');
                    return;
                end
        
                % ── Orientar: filas = muestras ──────────────────────────────────────
                if size(rawSignal,1) < size(rawSignal,2)
                    rawSignal = rawSignal';
                end
        
                % ── Limpiar NaN/Inf en la señal cargada ────────────────────────────
                rawSignal(~isfinite(rawSignal)) = 0;
        
                % ── Asignar canales ─────────────────────────────────────────────────
                if size(rawSignal,2) >= 2
                    app.canalEntrada1 = rawSignal(:,1);
                    app.canalEntrada2 = rawSignal(:,2);
                    app.numCanales    = 2;
                else
                    app.canalEntrada1 = rawSignal(:,1);
                    app.canalEntrada2 = [];
                    app.numCanales    = 1;
                end
        
                % ── Completar metadatos faltantes ───────────────────────────────────
                metaCompleta = app.completarMetadatos(meta);
                if isempty(metaCompleta), return; end
        
                % ── Guardar y actualizar interfaz ───────────────────────────────────
                app.metadatosSujeto.ID         = metaCompleta.ID;
                app.metadatosSujeto.Genero     = metaCompleta.Genero;
                app.metadatosSujeto.Frase      = metaCompleta.Frase;
                app.metadatosSujeto.Repeticion = metaCompleta.Repeticion;
                app.frecMuestEntrada           = metaCompleta.Fs;
                app.actualizarGraficosEntrada();
        
            catch ME
                uialert(app.UIFigure, ['Error de carga: ' ME.message], 'Error');
            end
        end

        % Button pushed function: GrabarDataset
        function GrabarDatasetButtonPushed(app, event)
            % Llamamos a la función de captura integrada
            app.capturarDatosDesdeHardware();
            
            % Opcional: Actualizar metadatos si es necesario
            app.DisplayFrecMuestreo.Value = num2str(app.frecMuestEntrada);
        end

        % Button pushed function: GenerarSealSinteticaButton
        function GenerarSealSinteticaButtonPushed(app, event)
            tipos = {'Seno', 'Coseno', 'Sinc', 'Chirp'};
            [indx, ok] = listdlg('PromptString', 'Selecciona el tipo de señal:', ...
                                 'SelectionMode', 'single', ...
                                 'ListString', tipos, ...
                                 'Name', 'Tipo de Señal');
            if ok == 0, return; end
            tipoSeleccionado = tipos{indx};
        
            prompt   = {'Amplitud:', 'Duración (s):', 'Frecuencia Análoga (Hz):', 'Frecuencia de Muestreo (Hz):'};
            definput = {'1', '5', '1', '20'};
        
            if strcmp(tipoSeleccionado, 'Chirp')
                prompt{end+1}   = 'Frecuencia Final (Chirp - Hz):';
                definput{end+1} = '10';
            end
        
            respuesta = inputdlg(prompt, 'Parámetros de la Señal Sintética', [1 50], definput);
            if isempty(respuesta), return; end
        
            A     = str2double(respuesta{1});
            Tfull = str2double(respuesta{2});
            Fa    = str2double(respuesta{3});
            Fs    = str2double(respuesta{4});
            Ff    = 0;
            if strcmp(tipoSeleccionado, 'Chirp')
                Ff = str2double(respuesta{5});
            end
        
            t = 0:(1/Fs):Tfull;
            switch tipoSeleccionado
                case 'Seno',   out = A * sin(2*pi*Fa*t);
                case 'Coseno', out = A * cos(2*pi*Fa*t);
                case 'Sinc',   out = A * sinc(2*Fa*(t - Tfull/2));
                case 'Chirp',  out = A * chirp(t, Fa, Tfull, Ff);
            end
        
            app.DatosGuardados = out;
            app.FsActual       = Fs;
        
            % ── Metadatos para señal sintética ──
            app.metadatosSujeto.ID         = tipoSeleccionado;
            app.metadatosSujeto.Genero     = 'N/A';
            app.metadatosSujeto.Frase      = 'N/A';
            app.metadatosSujeto.Repeticion = 0;
        
            app.DisplaySujeto.Value       = tipoSeleccionado;
            app.DisplayGenero.Value       = 'N/A';
            app.DisplayFrase.Value        = 'N/A';
            app.DisplayRepeticion.Value   = 'N/A';
            app.DisplayFrecMuestreo.Value = num2str(Fs);
        
            cargarSenalEstereoEntrada(app, out(:), Fs);
            title(app.axes_in, ['Señal Sintética: ' tipoSeleccionado], 'Color', 'w');
        end

        % Button pushed function: RealizarPreprocesamientoButton
        function RealizarPreprocesamientoButtonPushed(app, event)
            x1 = app.canalEntrada1;
            x2 = app.canalEntrada2;
            fs_original = app.frecMuestEntrada;
        
            if isempty(x1)
                uialert(app.UIFigure, 'No hay señal cargada para procesar.', 'Error');
                return;
            end
        
            x1 = x1(:); x2 = x2(:);
            if app.numCanales == 1 || isempty(x2), x2 = x1; end
            x1(~isfinite(x1)) = 0; x2(~isfinite(x2)) = 0;
            
            t_original = (0:numel(x1)-1)  / fs_original;
            T_total = t_original(end);
            
            % ==============================================================
            % ZONA DE CONFIGURACIÓN DEL PROTOCOLO (INTERACTIVA)
            % ==============================================================
            % 1. Preguntar por la segmentación
            respuesta = uiconfirm(app.UIFigure, ...
                '¿Desea recortar los silencios de grabación o pasar toda la señal?', ...
                'Opciones de Segmentación', ...
                'Options', {'Dataset Privado (Cortar 1.5s)', 'Señal Completa'}, ...
                'DefaultOption', 'Señal Completa');
                
            if strcmp(respuesta, 'Dataset Privado (Cortar 1.5s)')
                t_ini = 1.5;                         % Recorta 1.5s al inicio
                t_fin = max(1.5, T_total - 1.5);     % Recorta 1.5s al final
            else
                t_ini = 0.0;                         % Inicia desde el segundo 0
                t_fin = T_total;                     % Va hasta el final exacto
            end
            
            % 2. Preguntar por el tipo de ventana
            tipo_ventana = uiconfirm(app.UIFigure, ...
                'Seleccione la ventana matemática a aplicar:', ...
                'Tipo de Ventana', ...
                'Options', {'Hanning', 'Hamming', 'Rectangular'}, ...
                'DefaultOption', 'Hanning');
            
            fs_nueva = fs_original;   
            % ==============================================================
            
            % 1. SEGMENTACIÓN (Partición de la señal)
            idx_ini = max(1, round(t_ini * fs_original) + 1);
            idx_fin = min(length(x1), round(t_fin * fs_original) + 1);
            
            x1_recortada = x1(idx_ini:idx_fin);
            x2_recortada = x2(idx_ini:idx_fin);
            x_mat_recortada = [x1_recortada, x2_recortada];
        
            % 2. REMUESTREO
            if fs_nueva ~= fs_original && fs_nueva > 0
                [p, q] = rat(fs_nueva / fs_original);
                x_resampled = resample(x_mat_recortada, p, q);
            else
                x_resampled = x_mat_recortada;
                fs_nueva = fs_original;
            end
        
            % 3. NORMALIZACIÓN Z-SCORE
            mu    = mean(x_resampled, 1);
            sigma = std(x_resampled, 0, 1);
            sigma(sigma == 0) = 1;
            x_pre = (x_resampled - mu) ./ sigma;
        
            % 4. ENVENTANADO (Aplicado al segmento útil)
            N_seg = size(x_pre, 1);
            switch tipo_ventana
                case 'Hanning'
                    w = hann(N_seg);
                case 'Hamming'
                    w = hamming(N_seg);
                case 'Rectangular'
                    w = rectwin(N_seg);
                otherwise
                    w = rectwin(N_seg); 
            end
            x_final = x_pre .* w;
            if size(x_final,1) < size(x_final,2)
                x_final = x_final';
            end
            
            % 5. GRAFICACIÓN
            t_nuevo    = (0:size(x_final,1)-1)  / fs_nueva;
            offset_final = max(abs(x_final(:,1))) + max(abs(x_final(:,2)));
            usarStem = (size(x_final,1) <= 500);
        
            cla(app.axes_pre);
            hold(app.axes_pre, 'on');
            if app.numCanales == 2
                if usarStem
                    stem(app.axes_pre, t_nuevo, x_final(:,1) - offset_final/2, 'Color',[0 0.7 0.9]);
                    stem(app.axes_pre, t_nuevo, x_final(:,2) + offset_final/2, 'Color',[0.9 0.4 0]);
                else
                    plot(app.axes_pre, t_nuevo, x_final(:,1) - offset_final/2, 'Color',[0 0.7 0.9]);
                    plot(app.axes_pre, t_nuevo, x_final(:,2) + offset_final/2, 'Color',[0.9 0.4 0]);
                end
                legend(app.axes_pre, {'Canal 1','Canal 2'}, 'TextColor','w', 'Color','none');
            else
                if usarStem
                    stem(app.axes_pre, t_nuevo, x_final(:,1), 'Color',[0 0.7 0.9]);
                else
                    plot(app.axes_pre, t_nuevo, x_final(:,1), 'Color',[0 0.7 0.9]);
                end
                legend(app.axes_pre, {'Canal 1'}, 'TextColor','w', 'Color','none');
            end
            hold(app.axes_pre, 'off');
            title(app.axes_pre, ['Señal Preprocesada Final (Ventana: ', tipo_ventana, ')'], 'Color','w');
            
            % ---- AJUSTE DE ZOOM AUTOMÁTICO ----
            app.axes_pre.XLimMode = 'auto';
            app.axes_pre.YLimMode = 'auto';
            
            app.DatosPreprocesados = x_final;
            app.FsActual = fs_nueva;
            app.DisplayFrecMuestreo.Value = num2str(fs_nueva);
        end

        % Button pushed function: GuardarSalida
        function GuardarSalidaButtonPushed(app, event)
                   if isempty(app.canalEntrada1)
                uialert(app.UIFigure, 'No hay señal para guardar.', 'Aviso');
                return;
            end
        
            seleccion = uiconfirm(app.UIFigure, ...
                '¿Qué señal desea guardar?', 'Guardar Señal', ...
                'Options', {'Señal de Entrada', 'Señal de Salida (Procesada)'});
        
            if strcmp(seleccion, 'Señal de Salida (Procesada)')
                if isempty(app.canalSalida1)
                    uialert(app.UIFigure, 'No hay señal de salida procesada.', 'Aviso');
                    return;
                end
                if ~isempty(app.canalSalida2)
                    datos = [app.canalSalida1(:), app.canalSalida2(:)];
                else
                    datos = app.canalSalida1(:);
                end
                nombreDefault = 'senal_salida';
            else
                if app.numCanales == 2 && ~isempty(app.canalEntrada2)
                    datos = [app.canalEntrada1(:), app.canalEntrada2(:)];
                else
                    datos = app.canalEntrada1(:);
                end
                nombreDefault = 'senal_entrada';
            end
        
            [file, path] = uiputfile({'*.mat','MAT-file'; '*.csv','CSV'}, ...
                                      'Guardar señal', nombreDefault);
            if isequal(file, 0), return; end
        
            [~, ~, ext] = fileparts(file);
            fullPath    = fullfile(path, file);
        
            if strcmp(ext, '.mat')
                senal = datos;
                save(fullPath, 'senal');
            else
                writematrix(datos, fullPath);
            end
        
            uialert(app.UIFigure, 'Señal guardada correctamente.', 'Éxito');
        end

        % Button pushed function: DominioTButton
        function DominioTButtonPushed(app, event)
            if isempty(app.DatosPreprocesados)
            uialert(app.UIFigure, 'Realice el preprocesamiento primero.', 'Error');
            return;
            end
        
        Fs = app.FsActual;
        x1 = app.DatosPreprocesados(:,1);
        x2 = app.DatosPreprocesados(:,2);
        N  = length(x1);
        t  = (0:N-1) / Fs;
        
        operacion = uiconfirm(app.UIFigure, ...
            'Seleccione la operación en dominio del tiempo:', ...
            'Dominio del Tiempo', ...
            'Options', {'Cambio de Frecuencia de Muestreo', 'Desplazamiento en Tiempo', 'Ajuste de Magnitud'});
        
        switch operacion
        
            case 'Cambio de Frecuencia de Muestreo'
                resp = inputdlg({'Nueva frecuencia de muestreo (Hz):'}, ...
                                'Cambio Fs', [1 50], {num2str(Fs)});
                if isempty(resp), return; end
                Fs_nueva = str2double(resp{1});
        
                if Fs_nueva <= 0 || isnan(Fs_nueva)
                    uialert(app.UIFigure, 'Frecuencia inválida.', 'Error');
                    return;
                end
        
                [p, q] = rat(Fs_nueva / Fs);
                y1 = resample(x1, p, q);
                y2 = resample(x2, p, q);
                t_salida = (0:length(y1)-1) / Fs_nueva;
        
                % 1. Evaluar si es sobremuestreo o submuestreo
                if Fs_nueva > Fs
                    tipo_str = 'Sobremuestreo';
                else
                    tipo_str = 'Submuestreo';
                end
                
                % 2. Crear el título concatenando los textos y números
                titulo_op = [tipo_str, ' (', num2str(Fs), ' Hz → ', num2str(Fs_nueva), ' Hz)'];
        
                app.FsActual = Fs_nueva;
                app.DisplayFrecMuestreo.Value = num2str(Fs_nueva);
        
            case 'Desplazamiento en Tiempo'
                resp = inputdlg({'Desplazamiento en muestras (positivo=atraso, negativo=adelanto):'}, ...
                                'Desplazamiento', [1 60], {'10'});
                if isempty(resp), return; end
                desp = round(str2double(resp{1}));
        
                if isnan(desp)
                    uialert(app.UIFigure, 'Valor inválido.', 'Error');
                    return;
                end
        
                if desp >= 0
                    y1 = [zeros(desp,1); x1(1:end-desp)];
                    y2 = [zeros(desp,1); x2(1:end-desp)];
                    tipo_str = ['Atraso de ' num2str(desp) ' muestras'];
                else
                    d  = abs(desp);
                    y1 = [x1(d+1:end); zeros(d,1)];
                    y2 = [x2(d+1:end); zeros(d,1)];
                    tipo_str = ['Adelanto de ' num2str(d) ' muestras'];
                end
                titulo_op = ['Desplazamiento: ' tipo_str];
                t_salida  = t;
        
            case 'Ajuste de Magnitud'
                resp = inputdlg({'Constante de escala (multiplica la señal):'}, ...
                                'Ajuste de Magnitud', [1 50], {'2'});
                if isempty(resp), return; end
                K = str2double(resp{1});
        
                if isnan(K)
                    uialert(app.UIFigure, 'Valor inválido.', 'Error');
                    return;
                end
        
                y1 = K * x1;
                y2 = K * x2;
                titulo_op = ['Ajuste de Magnitud: x' num2str(K)];
                t_salida  = t;
        end
        
        % Offsets visuales para separar canales
        offset_in  = max(abs(x1))  + max(abs(x2));
        offset_out = max(abs(y1))  + max(abs(y2));
        
        figDT = figure('Name', ['Dominio del Tiempo: ' titulo_op], 'Color', [0.1 0.1 0.1]);
        
        axEnt1 = subplot(3,2,1, 'Parent', figDT);
        plot(axEnt1, t, x1, 'Color', [0 0.7 0.9], 'LineWidth', 0.8);
        title(axEnt1, 'Entrada C1 (Preprocesada)', 'Color', 'w');
        xlabel(axEnt1, 'Tiempo (s)'); ylabel(axEnt1, 'Amplitud');
        
        axEnt2 = subplot(3,2,2, 'Parent', figDT);
        plot(axEnt2, t, x2, 'Color', [0.9 0.4 0], 'LineWidth', 0.8);
        title(axEnt2, 'Entrada C2 (Preprocesada)', 'Color', 'w');
        xlabel(axEnt2, 'Tiempo (s)'); ylabel(axEnt2, 'Amplitud');
        
        axSal = subplot(3,2,[3,4,5,6], 'Parent', figDT);
        hold(axSal,'on');
        plot(axSal, t_salida, y1 - offset_out/2, 'Color', [0 0.7 0.9], 'LineWidth', 0.8);
        plot(axSal, t_salida, y2 + offset_out/2, 'Color', [0.9 0.4 0],  'LineWidth', 0.8);
        hold(axSal,'off');
        title(axSal, ['Salida: ' titulo_op ' (Cian: C1 ↓, Naranja: C2 ↑)'], 'Color', 'w');
        xlabel(axSal, 'Tiempo (s)'); ylabel(axSal, 'Amplitud');
        legend(axSal, {'Canal 1','Canal 2'}, 'TextColor','w','Color','none');
        
        set([axEnt1, axEnt2, axSal], 'Color','k','XColor','w','YColor','w');
        grid(axEnt1,'on'); grid(axEnt2,'on'); grid(axSal,'on');
        
        cla(app.axes_out);
        hold(app.axes_out,'on');
        plot(app.axes_out, t_salida, y1 - offset_out/2, 'Color', [0 0.7 0.9], 'LineWidth', 0.8);
        plot(app.axes_out, t_salida, y2 + offset_out/2, 'Color', [0.9 0.4 0],  'LineWidth', 0.8);
        hold(app.axes_out,'off');
        title(app.axes_out, ['Salida DominioT: ' titulo_op ' (Cian: C1 ↓, Naranja: C2 ↑)'], 'Color', 'w');
        xlabel(app.axes_out, 'Tiempo (s)'); ylabel(app.axes_out, 'Amplitud');
        grid(app.axes_out,'on');
        
        app.canalSalida1       = y1;
        app.canalSalida2       = y2;
        app.canalEntrada1      = y1;
        app.canalEntrada2      = y2;
        app.DatosPreprocesados = [y1(:), y2(:)];
        end

        % Button pushed function: BorrarSealesButton
        function BorrarSealesButtonPushed(app, event)
            confirmacion = uiconfirm(app.UIFigure, ...
                '¿Está seguro de que desea borrar todas las señales?', ...
                'Confirmar borrado', ...
                'Options', {'Sí, borrar', 'Cancelar'}, ...
                'DefaultOption', 'Cancelar', ...
                'CancelOption', 'Cancelar');
        
            if strcmp(confirmacion, 'Cancelar'), return; end
        
            % Limpiar gráficas
            cla(app.axes_in);
            cla(app.axes_pre);
            cla(app.axes_out);
        
            title(app.axes_in,  'Señal de Entrada',           'Color', 'w');
            title(app.axes_pre, 'Señal Preprocesada',          'Color', 'w');
            title(app.axes_out, 'Señal de Salida (Procesada)', 'Color', 'w');
        
            % Limpiar los 5 campos de metadatos
            app.DisplaySujeto.Value       = '';
            app.DisplayGenero.Value       = '';
            app.DisplayFrase.Value        = '';
            app.DisplayRepeticion.Value   = '';
            app.DisplayFrecMuestreo.Value = '';
        
            % Limpiar datos en memoria
            app.canalEntrada1      = [];
            app.canalEntrada2      = [];
            app.canalSalida1       = [];
            app.canalSalida2       = [];
            app.DatosGuardados     = [];
            app.DatosPreprocesados = [];
            app.FsActual           = [];
            app.frecMuestEntrada   = [];
            app.numCanales         = 1;
            app.metadatosSujeto    = struct('ID','','Genero','','Frase','','Repeticion',0);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axes_in = uiaxes(app.UIFigure);
            title(app.axes_in, 'Señal de Entrada')
            xlabel(app.axes_in, 'Tiempo (seg)')
            ylabel(app.axes_in, 'Amplitud')
            zlabel(app.axes_in, 'Z')
            app.axes_out = uiaxes(app.UIFigure);
            title(app.axes_out, 'Señal de Salida (Procesada)')
            xlabel(app.axes_out, 'Tiempo (seg)')
            ylabel(app.axes_out, 'Amplitud')
            zlabel(app.axes_out, 'Z')
            app.axes_pre = uiaxes(app.UIFigure);
            title(app.axes_pre, 'Señal Preprocesada')
            xlabel(app.axes_pre, 'Tiempo (seg)')
            ylabel(app.axes_pre, 'Amplitud')
            zlabel(app.axes_pre, 'Z')
            app.IDLabel = uilabel(app.UIFigure);
            app.IDLabel.Text = 'ID';
            app.DisplaySujeto = uieditfield(app.UIFigure, 'text');
            app.DisplaySujeto.Editable = 'off';
            app.FrecuenciadeMuestreoEditFieldLabel = uilabel(app.UIFigure);
            app.FrecuenciadeMuestreoEditFieldLabel.Text = 'Frecuencia de Muestreo';
            app.DisplayFrecMuestreo = uieditfield(app.UIFigure, 'text');
            app.DisplayFrecMuestreo.Editable = 'off';
            app.SealdeAudioPanel = uipanel(app.UIFigure);
            app.SealdeAudioPanel.Title = 'Señal de Audio';
            app.Lamp = uilamp(app.SealdeAudioPanel);
            app.Lamp.Enable = 'off';
            app.EscucharAudio = uibutton(app.SealdeAudioPanel, 'push');
            app.EscucharAudio.ButtonPushedFcn = createCallbackFcn(app, @EscucharAudioButtonPushed, true);
            app.EscucharAudio.Text = 'Escuchar';
            app.CargarAudio = uibutton(app.SealdeAudioPanel, 'push');
            app.CargarAudio.ButtonPushedFcn = createCallbackFcn(app, @CargarAudioButtonPushed, true);
            app.CargarAudio.Text = 'Cargar';
            app.GrabarButton = uibutton(app.SealdeAudioPanel, 'state');
            app.GrabarButton.ValueChangedFcn = createCallbackFcn(app, @GrabarButtonValueChanged, true);
            app.GrabarButton.Text = 'Grabar';
            app.PreprocesamientoPanel = uipanel(app.UIFigure);
            app.PreprocesamientoPanel.Title = 'Preprocesamiento';
            app.RealizarPreprocesamientoButton = uibutton(app.PreprocesamientoPanel, 'push');
            app.RealizarPreprocesamientoButton.ButtonPushedFcn = createCallbackFcn(app, @RealizarPreprocesamientoButtonPushed, true);
            app.RealizarPreprocesamientoButton.Text = 'Realizar Preprocesamiento';
            app.SealDatasetPanel = uipanel(app.UIFigure);
            app.SealDatasetPanel.Title = 'Señal Dataset';
            app.GrabarDataset = uibutton(app.SealDatasetPanel, 'push');
            app.GrabarDataset.ButtonPushedFcn = createCallbackFcn(app, @GrabarDatasetButtonPushed, true);
            app.GrabarDataset.Text = 'Grabar Nueva';
            app.CargarDataset = uibutton(app.SealDatasetPanel, 'push');
            app.CargarDataset.ButtonPushedFcn = createCallbackFcn(app, @CargarDatasetButtonPushed, true);
            app.CargarDataset.Text = 'Cargar';
            app.ProcesamientoPanel = uipanel(app.UIFigure);
            app.ProcesamientoPanel.Title = 'Procesamiento';
            app.TransformacionesButton = uibutton(app.ProcesamientoPanel, 'push');
            app.TransformacionesButton.ButtonPushedFcn = createCallbackFcn(app, @TransformacionesButtonPushed, true);
            app.TransformacionesButton.Text = 'Transformaciones';
            app.FiltrosButton = uibutton(app.ProcesamientoPanel, 'push');
            app.FiltrosButton.ButtonPushedFcn = createCallbackFcn(app, @FiltrosButtonPushed, true);
            app.FiltrosButton.Text = 'Filtros';
            app.DominioTButton = uibutton(app.ProcesamientoPanel, 'push');
            app.DominioTButton.ButtonPushedFcn = createCallbackFcn(app, @DominioTButtonPushed, true);
            app.DominioTButton.Text = 'DominioT';
            app.SealSintticaPanel = uipanel(app.UIFigure);
            app.SealSintticaPanel.Title = 'Señal Sintética';
            app.GenerarSealSinteticaButton = uibutton(app.SealSintticaPanel, 'push');
            app.GenerarSealSinteticaButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarSealSinteticaButtonPushed, true);
            app.GenerarSealSinteticaButton.Text = 'Generar Señal Sintetica';
            app.Panel = uipanel(app.UIFigure);
            app.GuardarSalida = uibutton(app.Panel, 'push');
            app.GuardarSalida.ButtonPushedFcn = createCallbackFcn(app, @GuardarSalidaButtonPushed, true);
            app.GuardarSalida.Text = 'Guardar Señales';
            app.BorrarSealesButton = uibutton(app.Panel, 'push');
            app.BorrarSealesButton.ButtonPushedFcn = createCallbackFcn(app, @BorrarSealesButtonPushed, true);
            app.BorrarSealesButton.Text = 'Borrar Señales';
            app.IdentificadorLabel_2 = uilabel(app.UIFigure);
            app.IdentificadorLabel_2.Text = 'Genero';
            app.DisplayGenero = uieditfield(app.UIFigure, 'text');
            app.DisplayGenero.Editable = 'off';
            app.ActividadLabel = uilabel(app.UIFigure);
            app.ActividadLabel.Text = 'Frase';
            app.DisplayFrase = uieditfield(app.UIFigure, 'text');
            app.DisplayFrase.Editable = 'off';
            app.RepeticinLabel = uilabel(app.UIFigure);
            app.RepeticinLabel.Text = 'Repetición';
            app.DisplayRepeticion = uieditfield(app.UIFigure, 'text');
            app.DisplayRepeticion.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final_Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end