classdef GrabarNuevaGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        DatasetNombre                matlab.ui.control.EditField
        DatasetEditFieldLabel        matlab.ui.control.Label
        EscogerDatasetButton         matlab.ui.control.Button
        ContinuarButton              matlab.ui.control.Button
        LabelTop                     matlab.ui.control.Label
        RepeticionEditField          matlab.ui.control.NumericEditField
        RepeticionEditFieldLabel     matlab.ui.control.Label
        IdentificadorEditField       matlab.ui.control.EditField
        IdentificadorEditFieldLabel  matlab.ui.control.Label
        SexoDropDown                 matlab.ui.control.DropDown
        SexoDropDownLabel            matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    properties (Access = private)
        Etapa = 0
        dataset
        genero
        archivo = 'dataset_emg.mat' % Por defecto crea un dataset con este nombre
        sobreescribir = 0 % solucion "temporal"
        omitir = 0 % tambien es una solucion "temporal"
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ContinuarButton
        function ContinuarButtonPushed(app, event)
            switch app.Etapa % Se avanza una etapa cada vez que se presiona el boton
                case 0
                    % =========================
                    % CARGAR O CREAR DATASET
                    % =========================
                    if exist(app.archivo, 'file')
                        load(app.archivo, 'dts');
                        app.dataset = dts;
                    else
                        app.dataset = struct([]);
                    end

                    % =========================
                    % BUSCAR SI EL SUJETO YA EXISTE
                    % =========================
                    indicesSujeto = [];
                    
                    for i = 1:length(app.dataset)
                        if strcmpi(app.dataset(i).idSujeto, app.IdentificadorEditField.Value)
                            indicesSujeto(end+1) = i;
                        end
                    end
        
                    % =========================
                    % PEDIR GÉNERO SOLO SI ES NUEVO
                    % =========================
                    if isempty(indicesSujeto)
                        app.LabelTop.Text = "Nuevo ID registrado. Presiona Continuar para seguir.";
                        app.genero = app.SexoDropDown.Value;
                    else
                        app.LabelTop.Text = "ID ya existente. Presiona Continuar para seguir.";
                        app.genero = app.dataset(indicesSujeto(1)).genero;
                    end

                    app.RepeticionEditField.Value = 0;
                    app.Etapa = 1;
                    app.SexoDropDown.Enable = "off";
                    app.IdentificadorEditField.Enable = "off";
                    app.EscogerDatasetButton.Enable = "off";

                case {1,2,3,4,5}
                    app.sobreescribir = 0;
                    app.omitir = 0;
                    app.RepeticionEditField.Value = app.Etapa;

                    % Ver si esa repetición ya existe
                    indiceExistente = [];
                    
                    for i = 1:length(app.dataset)
                        if strcmpi(app.dataset(i).idSujeto, app.IdentificadorEditField.Value) && app.dataset(i).repeticion == app.Etapa
                            indiceExistente = i;
                            break;
                        end
                    end
                    
                    if ~isempty(indiceExistente)
                        answer = uiconfirm(app.UIFigure, '¿Deseas sobreescribir esta repeticion?', ('Repeticion #' + string(app.Etapa) + ' ya existe'), 'Options', {'Si', 'No'});
                        if strcmp(answer, 'Yes')
                            app.sobreescribir = 1;
                        end
                    else
                        answer = uiconfirm(app.UIFigure, '¿Que deseas hacer con esta repeticion?', ('Repeticion #' + string(app.Etapa)), 'Options', {'Capturar', 'Omitir'});
                        if strcmp(answer, 'Omitir')
                            app.LabelTop.Text = ('Repeticion #' + string(app.Etapa) + ' omitida.');
                            app.omitir = 1;
                        end
                    end

                    % =========================
                    % SEÑAL (REEMPLAZA ESTO POR LA REAL)
                    % =========================
                    canal1 = randn(12000,1);
                    canal2 = randn(12000,1);
                    senal = [canal1 canal2];

                    % =========================
                    % VALIDACIONES
                    % =========================
                    if size(senal,2) ~= 2
                        app.LabelTop.Text = 'Error: la señal debe tener 2 canales.';
                    end
                    
                    if any(isnan(senal), 'all')
                        app.LabelTop.Text = 'Error: la señal contiene NaN.';
                    end
                    
                    if any(isinf(senal), 'all')
                        app.LabelTop.Text = 'Error: la señal contiene Inf.';
                    end
                    
                    % =========================
                    % CREAR REGISTRO
                    % =========================
                    registro.idSujeto = app.IdentificadorEditField.Value;
                    registro.genero = app.genero;
                    registro.repeticion = app.Etapa;
                    registro.senal = senal;
                    
                    % =========================
                    % GUARDAR O SOBRESCRIBIR
                    % =========================
                    if isempty(app.dataset)
                        app.dataset = registro;
                        app.LabelTop.Text = 'Primera repeticion del nuevo registro guardada.';
                    elseif isempty(indiceExistente) && (app.omitir == 0)
                        app.dataset(end+1) = registro;
                        app.LabelTop.Text = 'Repeticion #' + string(app.Etapa) + ' guardada.';
                    elseif app.sobreescribir == 1
                        app.dataset(indiceExistente) = registro;
                        app.LabelTop.Text = 'Repeticion #' + string(app.Etapa) + ' sobreescrita.';
                    else
                        app.LabelTop.Text = 'Repeticion #' + string(app.Etapa) + ' omitida.';
                    end
                    
                    dts = app.dataset;
                    save(app.archivo, 'dts');

                    if app.Etapa == 5
                        answer = uiconfirm(app.UIFigure, '¿Deseas ingresar otro sujeto?', 'Registro finalizado', 'Options', {'Si', 'No'});
                        if strcmp(answer, 'No')
                            delete(app);
                        end
                        app.Etapa = 0;
                        app.LabelTop.Text = "Ingresa los datos del sujeto.";
                        app.EscogerDatasetButton.Enable = "on";
                        app.SexoDropDown.Enable = "on";
                        app.IdentificadorEditField.Enable = "on";
                    else
                        app.Etapa = app.Etapa + 1;
                    end
            end
        end

        % Button pushed function: EscogerDatasetButton
        function EscogerDatasetButtonPushed(app, event)
            app.archivo = uigetfile;
            app.DatasetNombre.Value = string(app.archivo);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Previsualizacion')
            xlabel(app.UIAxes, 'Tiempo (Seg)')
            ylabel(app.UIAxes, 'Amplitud')
            zlabel(app.UIAxes, 'Z')
            app.SexoDropDownLabel = uilabel(app.UIFigure);
            app.SexoDropDownLabel.Text = 'Sexo';
            app.SexoDropDown = uidropdown(app.UIFigure);
            app.SexoDropDown.Items = {'Masculino', 'Femenino'};
            app.SexoDropDown.Value = 'Masculino';
            app.IdentificadorEditFieldLabel = uilabel(app.UIFigure);
            app.IdentificadorEditFieldLabel.Text = 'Identificador';
            app.IdentificadorEditField = uieditfield(app.UIFigure, 'text');
            app.RepeticionEditFieldLabel = uilabel(app.UIFigure);
            app.RepeticionEditFieldLabel.Enable = 'off';
            app.RepeticionEditFieldLabel.Text = 'Repeticion';
            app.RepeticionEditField = uieditfield(app.UIFigure, 'numeric');
            app.RepeticionEditField.Enable = 'off';
            app.LabelTop = uilabel(app.UIFigure);
            app.LabelTop.Text = 'Ingresa los datos del sujeto.';
            app.ContinuarButton = uibutton(app.UIFigure, 'push');
            app.ContinuarButton.ButtonPushedFcn = createCallbackFcn(app, @ContinuarButtonPushed, true);
            app.ContinuarButton.Text = 'Continuar';
            app.EscogerDatasetButton = uibutton(app.UIFigure, 'push');
            app.EscogerDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @EscogerDatasetButtonPushed, true);
            app.EscogerDatasetButton.Text = 'Escoger Dataset';
            app.DatasetEditFieldLabel = uilabel(app.UIFigure);
            app.DatasetEditFieldLabel.Enable = 'off';
            app.DatasetEditFieldLabel.Text = 'Dataset';
            app.DatasetNombre = uieditfield(app.UIFigure, 'text');
            app.DatasetNombre.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GrabarNuevaGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end