classdef InterfazFinal2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DisplayActividad                matlab.ui.control.EditField
        ActividadLabel                  matlab.ui.control.Label
        DisplayGenero                   matlab.ui.control.EditField
        IdentificadorLabel_2            matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        BorrarSealesButton              matlab.ui.control.Button
        GuardarSalida                   matlab.ui.control.Button
        SealSintticaPanel               matlab.ui.container.Panel
        GenerarSealSinteticaButton      matlab.ui.control.Button
        ProcesamientoPanel              matlab.ui.container.Panel
        DominioTButton                  matlab.ui.control.Button
        FiltrosButton                   matlab.ui.control.Button
        TransformacionesButton          matlab.ui.control.Button
        SealDatasetPanel                matlab.ui.container.Panel
        CargarDataset                   matlab.ui.control.Button
        GrabarDataset                   matlab.ui.control.Button
        PreprocesamientoPanel           matlab.ui.container.Panel
        RealizarPreprocesamientoButton  matlab.ui.control.Button
        SealdeAudioPanel                matlab.ui.container.Panel
        GrabarButton                    matlab.ui.control.StateButton
        CargarAudio                     matlab.ui.control.Button
        EscucharAudio                   matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        DisplayFrecMuestreo             matlab.ui.control.EditField
        FrecuenciadeMuestreoEditFieldLabel  matlab.ui.control.Label
        DisplaySujeto                   matlab.ui.control.EditField
        SujetoEditFieldLabel            matlab.ui.control.Label
        axes_pre                        matlab.ui.control.UIAxes
        axes_out                        matlab.ui.control.UIAxes
        axes_in                         matlab.ui.control.UIAxes
    end


    properties (Access = public)
        grabacionAudio = audiorecorder(44100,16,2) % Description
        canalEntrada1
        canalEntrada2
        frecMuestEntrada
        senalSalida
        metadatosSujeto
        canalSalida1  % Resultado procesado del canal izquierdo
        canalSalida2
        DatosGuardados % Aquí vive el vector de la señal (EMG o Sintética)
        FsActual       % Aquí guardas la frecuencia de muestreo Señal Sintética
        DatosPreprocesados
    end
    
    properties (Access = private)
       
    end
    
    methods (Access = public)
        function cargarSenalEstereoEntrada(app, senalIn, frecMuestreo)
            totalMuestrasSenal = length(senalIn);
            t = (0:totalMuestrasSenal-1) / frecMuestreo;
        
            columnas = size(senalIn, 2); 
            if columnas > 1
                app.canalEntrada1 = senalIn(:,1); 
                app.canalEntrada2 = senalIn(:,2);
            else
                app.canalEntrada1 = senalIn(:,1); 
                app.canalEntrada2 = senalIn(:,1); 
            end
        
            cla(app.axes_in);
            hold(app.axes_in, 'on');
            stem(app.axes_in, t, app.canalEntrada1, 'Color', [0 0.7 0.9], 'MarkerSize', 3, 'LineWidth', 0.6);
            stem(app.axes_in, t, app.canalEntrada2, 'Color', [0.9 0.4 0], 'MarkerSize', 3, 'LineWidth', 0.6);
            hold(app.axes_in, 'off');
            xlabel(app.axes_in, 'Tiempo (s)');
            ylabel(app.axes_in, 'Amplitud (V)');
            grid(app.axes_in, 'on');
        
            app.DisplayFrecMuestreo.Value = string(frecMuestreo);
            app.frecMuestEntrada = frecMuestreo;
        end 
        function actualizarGraficosEntrada(app)
            Fs = app.frecMuestEntrada;
            x1 = app.canalEntrada1;
            x2 = app.canalEntrada2;
            t = (0:length(x1)-1)/Fs;
            
            cla(app.axes_in);
            hold(app.axes_in, 'on');
            plot(app.axes_in, t, x1, 'Color', [0 0.7 0.9], 'LineWidth', 0.8); 
            plot(app.axes_in, t, x2, 'Color', [0.9 0.4 0],  'LineWidth', 0.8);
            hold(app.axes_in, 'off');
            
            title(app.axes_in, 'Señal de Entrada', 'Color', 'w');
            xlabel(app.axes_in, 'Tiempo (s)');
            ylabel(app.axes_in, 'Amplitud (V)');
            legend(app.axes_in, {'Canal 1', 'Canal 2'}, 'TextColor', 'w', 'Location', 'northeast');
            grid(app.axes_in, 'on');
            app.axes_in.Color = [0 0 0];
        
            % Metadatos en los campos de la interfaz
            app.DisplaySujeto.Value    = app.metadatosSujeto.ID;
            app.DisplayFrecMuestreo.Value = num2str(Fs);
            app.DisplayGenero.Value    = app.metadatosSujeto.Genero;
            app.DisplayActividad.Value = app.metadatosSujeto.Actividad;
end
        
        function data = readNPY(app, filename)
    % Function to read NPY files into matlab.
    % Recibe 'app' para poder llamar a readNPYheader como función privada.
    
    [shape, dataType, fortranOrder, littleEndian, totalHeaderLength, ~] = app.readNPYheader(filename);
    
    if littleEndian
        fid = fopen(filename, 'r', 'l');
    else
        fid = fopen(filename, 'r', 'b');
    end
    
    try
        [~] = fread(fid, totalHeaderLength, 'uint8');
        % read the data
        data = fread(fid, prod(shape), [dataType '=>' dataType]);
        
        if length(shape)>1 && ~fortranOrder
            data = reshape(data, shape(end:-1:1));
            data = permute(data, [length(shape):-1:1]);
        elseif length(shape)>1
            data = reshape(data, shape);
        end
        fclose(fid);
    catch me
        if fid ~= -1, fclose(fid); end
        rethrow(me);
    end
end
        
        function [arrayShape, dataType, fortranOrder, littleEndian, totalHeaderLength, npyVersion] = readNPYheader(app, filename)
    % parse the header of a .npy file and return all the info contained
    % Recibe 'app' como primer argumento para cumplir con el estándar de App Designer.
    
    fid = fopen(filename);
    % verify that the file exists
    if (fid == -1)
        if ~isempty(dir(filename))
            error('Permission denied: %s', filename);
        else
            error('File not found: %s', filename);
        end
    end
    
    try
        dtypesMatlab = {'uint8','uint16','uint32','uint64','int8','int16','int32','int64','single','double', 'logical'};
        dtypesNPY = {'u1', 'u2', 'u4', 'u8', 'i1', 'i2', 'i4', 'i8', 'f4', 'f8', 'b1'};
        
        magicString = fread(fid, [1 6], 'uint8=>uint8');
        
        if ~all(magicString == [147,78,85,77,80,89])
            error('readNPY:NotNUMPYFile', 'Error: El archivo no parece tener formato NUMPY.');
        end
        
        majorVersion = fread(fid, [1 1], 'uint8=>uint8');
        minorVersion = fread(fid, [1 1], 'uint8=>uint8');
        npyVersion = [majorVersion minorVersion];
        
        headerLength = fread(fid, [1 1], 'uint16=>uint16');
        totalHeaderLength = 10+headerLength;
        
        arrayFormat = fread(fid, [1 headerLength], 'char=>char');
        
        % Parseo de descripción de datos
        r = regexp(arrayFormat, '''descr''\s*:\s*''(.*?)''', 'tokens');
        if isempty(r), error('No se pudo parsear el formato: "%s"', arrayFormat); end
        dtNPY = r{1}{1};    
        
        littleEndian = ~strcmp(dtNPY(1), '>');
        dataType = dtypesMatlab{strcmp(dtNPY(2:3), dtypesNPY)};
            
        % Parseo de orden (Fortran/C)
        r = regexp(arrayFormat, '''fortran_order''\s*:\s*(\w+)', 'tokens');
        fortranOrder = strcmp(r{1}{1}, 'True');
        
        % Parseo de dimensiones (Shape)
        r = regexp(arrayFormat, '''shape''\s*:\s*\((.*?)\)', 'tokens');
        shapeStr = r{1}{1}; 
        arrayShape = str2num(shapeStr(shapeStr~='L'));
        
        fclose(fid);
        
    catch me
        if fid ~= -1, fclose(fid); end
        rethrow(me);
    end
        end
    end 
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: GrabarButton
        function GrabarButtonValueChanged(app, event)
            if app.GrabarButton.Value == 1
        % REASIGNAR EL OBJETO AQUÍ ES LA CLAVE
                app.grabacionAudio = audiorecorder(44100, 16, 2); 
        
                app.Lamp.Color = 'green';
                app.Lamp.Enable = 'on';
        
                % Iniciar grabación
                record(app.grabacionAudio);
            else
                % Detener grabación
                stop(app.grabacionAudio);
                app.Lamp.Color = [0.15 0.15 0.15]; % Color gris oscuro
            end
        end

        % Button pushed function: EscucharAudio
        function EscucharAudioButtonPushed(app, event)
            % Obtenemos los datos numéricos de la señal que ya viste en la gráfica
    y = getaudiodata(app.grabacionAudio);
    fs = 44100; % La frecuencia que definiste
    
    if ~isempty(y)
        % sound es una función básica de MATLAB que suele ser más directa
        sound(y, fs); 
    else
        uialert(app.UIFigure, 'No hay datos de audio', 'Aviso');
    end
        end

        % Button pushed function: CargarAudio
        function CargarAudioButtonPushed(app, event)
           datosRaw = getaudiodata(app.grabacionAudio); 
            fs = 44100;
        
            if isempty(datosRaw)
                uialert(app.UIFigure, 'No hay audio grabado.', 'Aviso');
                return;
            end
        
            app.DatosGuardados = datosRaw; 
            app.FsActual = fs;
        
            app.DisplaySujeto.Value    = 'Audio';
            app.DisplayGenero.Value    = 'N/A';
            app.DisplayActividad.Value = 'N/A';
            app.DisplayFrecMuestreo.Value = num2str(fs);
        
            cargarSenalEstereoEntrada(app, datosRaw, fs); 
            title(app.axes_in, 'Entrada de Audio Real', 'Color', 'w');
        end

        % Callback function
        function TransformacionDropDownValueChanged(app, event)
            value = app.TransformacionDropDown.Value;
    if isempty(app.canalEntrada1)
        uialert(app.UIFigure, 'Cargue una señal primero', 'Error');
        return; 
    end
    
    Fs = app.frecMuestEntrada; 
    x = app.canalEntrada1; 
    N = length(x);
    t = (0:N-1)/Fs;

    % Crear figura externa con fondo oscuro para mejor visibilidad
    figSalida = figure('Name', ['Análisis de ' char(value)], 'NumberTitle', 'off', 'Color', [0.1 0.1 0.1]);

    switch value
        case "Fourier"
            % --- 1.3.5: DFT con Zero-Padding y Fase [cite: 52, 53] ---
            nfft = 2^nextpow2(N * 2); 
            X = fft(x, nfft);
            f = (0:nfft/2) * (Fs/nfft);
            
            mag = abs(X(1:nfft/2+1)) / N;
            mag(2:end-1) = 2*mag(2:end-1);
            fase = angle(X(1:nfft/2+1));

            % Magnitud
            ax1 = subplot(3,1,1, 'Parent', figSalida);
            plot(ax1, f, mag, 'Color', 'cyan'); 
            title(ax1, 'Magnitud DFT (con Zero-Padding)', 'Color', 'w');
            xlabel(ax1, 'Frecuencia (Hz)'); ylabel(ax1, 'Amplitud Lineal');
            grid(ax1, 'on'); set(ax1, 'Color', 'k', 'XColor', 'w', 'YColor', 'w');

            % Fase
            ax2 = subplot(3,1,2, 'Parent', figSalida);
            plot(ax2, f, fase, 'Color', 'magenta'); 
            title(ax2, 'Fase de la DFT', 'Color', 'w');
            xlabel(ax2, 'Frecuencia (Hz)'); ylabel(ax2, 'Fase (Radianes)');
            grid(ax2, 'on'); set(ax2, 'Color', 'k', 'XColor', 'w', 'YColor', 'w');

            % Espectrograma Adaptativo 
            ax3 = subplot(3,1,3, 'Parent', figSalida);
            winSize = min(256, floor(N/4)); 
            [S, F_spec, T_spec, P] = spectrogram(x, winSize, floor(winSize/2), winSize, Fs, 'yaxis');
            surf(ax3, T_spec, F_spec, 10*log10(abs(P)+eps), 'EdgeColor', 'none'); 
            view(ax3, 0, 90); axis(ax3, 'tight');
            title(ax3, 'Espectrograma (Distribución Tiempo-Frecuencia)', 'Color', 'w');
            xlabel(ax3, 'Tiempo (s)'); ylabel(ax3, 'Frecuencia (Hz)');
            set(ax3, 'XColor', 'w', 'YColor', 'w');

        case "Coseno"
            % --- 1.3.6: DCT y Filtrado Ideal [cite: 56, 57, 58] ---
            y_dct = dct(x);
            corte = round(0.15 * N);
            y_dct_filtrada = zeros(size(y_dct));
            y_dct_filtrada(1:corte) = y_dct(1:corte);
            x_rec = idct(y_dct_filtrada);

            % Magnitud DCT
            ax1 = subplot(2,1,1, 'Parent', figSalida);
            plot(ax1, abs(y_dct), 'Color', [1 0.5 0]); hold(ax1, 'on');
            plot(ax1, abs(y_dct_filtrada), 'Color', 'g', 'LineWidth', 1); hold(ax1, 'off');
            title(ax1, 'Magnitud DCT: Original vs Filtro Ideal', 'Color', 'w');
            xlabel(ax1, 'Índice de Coeficiente'); ylabel(ax1, 'Amplitud');
            legend(ax1, 'Original', 'Filtrada', 'TextColor', 'w', 'Color', 'none');
            set(ax1, 'Color', 'k', 'XColor', 'w', 'YColor', 'w');

            % Señal Reconstruida
            ax2 = subplot(2,1,2, 'Parent', figSalida);
            plot(ax2, t, x, 'Color', [0.5 0.5 0.5]); hold(ax2, 'on');
            plot(ax2, t, x_rec, 'Color', 'w', 'LineWidth', 1.2); hold(ax2, 'off');
            title(ax2, 'Señal de Entrada vs Salida Filtrada (IDCT)', 'Color', 'w');
            xlabel(ax2, 'Tiempo (s)'); ylabel(ax2, 'Amplitud');
            set(ax2, 'Color', 'k', 'XColor', 'w', 'YColor', 'w');

        case "Wavelet"
            % --- 1.3.7: Wavelet db6 a 3 niveles [cite: 60, 61, 63] ---
            [c, l] = wavedec(x, 3, 'db6');
            A3 = appcoef(c, l, 'db6', 3);
            [D1, D2, D3] = detcoef(c, l, [1 2 3]);

            titulos = {'Aproximación A3 (Baja Frec)', 'Detalle D3', 'Detalle D2', 'Detalle D1 (Alta Frec)'};
            datos = {A3, D3, D2, D1};
            colores = {'c', 'g', 'y', 'r'};
            
            for i = 1:4
                ax = subplot(4,1,i, 'Parent', figSalida);
                plot(ax, datos{i}, colores{i});
                title(ax, titulos{i}, 'Color', 'w');
                xlabel(ax, 'Muestras'); ylabel(ax, 'Amplitud');
                set(ax, 'Color', 'k', 'XColor', 'w', 'YColor', 'w');
            end
    end
        end

        % Callback function
        function CargarSinteticaButtonPushed(app, event)
            A = app.SintAmplitud.Value;
            Tfull = app.SintDuracion.Value;
            Fa = app.SintFrecAnal.Value;
            Ff = app.SintFrecFinal.Value;
            Fs = app.SintFrecMuestreo.Value;
            out = [];

            t = 0:(1/Fs):Tfull;
            switch app.TipoDropDown.Value
                case "Seno"
                    out = A*sin(2*pi*Fa*t);
                    app.DisplaySujeto.Value = "Seno";
                case "Coseno"
                    out = A*cos(2*pi*Fa*t);
                    app.DisplaySujeto.Value = "Coseno";
                case "Sinc"
                    out = A*sinc(2*pi*Fa*t);
                    app.DisplaySujeto.Value = "Seno Cardinal";
                case "Chirp"
                    out = A*chirp(t,Fa,Tfull,Ff);
                    app.DisplaySujeto.Value = "Chirp";
            end
            cargarSenalEstereoEntrada(app, [out(:), out(:)], Fs);
        end

        % Callback function
        function FiltroDropDownValueChanged(app, event)
            if isempty(app.canalEntrada1)
                uialert(app.UIFigure, 'Cargue una señal primero', 'Error');
            return; 
            end

    % Aseguramos que la entrada sea un vector fila o columna simple
    x = app.canalEntrada1(:); 
    Fs = app.frecMuestEntrada;
    t = (0:length(x)-1)/Fs;
    
    
    origen = uiconfirm(app.UIFigure, '¿Cómo desea ingresar los parámetros?', ...
        'Configuración del Sistema', 'Options', {'Ventana de Diálogo', 'Desde Archivo'});

    if strcmp(origen, 'Desde Archivo')
        [file, path] = uigetfile('*.txt', 'Seleccione el archivo de coeficientes');
        if isequal(file, 0), return; end
        data = importdata(fullfile(path, file));
        % Si es FIR, el archivo debe tener una fila. Si es IIR, dos.
        if isstruct(data), coeffs_file = data.data; else, coeffs_file = data; end
    end

    tipoFiltro = uiconfirm(app.UIFigure, 'Seleccione el tipo de sistema:', ...
        'Procesamiento 1.3.3', 'Options', {'Sistema FIR', 'Sistema IIR'});

    switch tipoFiltro
        case 'Sistema FIR'
            if strcmp(origen, 'Ventana de Diálogo')
                promptH = {'Ingrese los coeficientes h(n) (separados por espacio):'};
                h_str = inputdlg(promptH, 'Parámetros h(n)', [1 70], {'0.2 0.5 0.2'});
                if isempty(h_str), return; end
                h = str2num(h_str{1}); 
            else
                h = coeffs_file(1, :); % Toma la primera fila del archivo
            end
            
            
            h = h(:); 
            
            % Implementación FIR (y(n) = sum x(n-k)h(k)) 
            y = conv(x, h, 'same'); 
            metodo = 'FIR (Convolucion)';

        case 'Sistema IIR'
            if strcmp(origen, 'Ventana de Diálogo')
                promptIIR = {'Coeficientes b_k (numerador):', 'Coeficientes a_k (denominador):'};
                coeffs = inputdlg(promptIIR, 'Ecuación de Diferencia', [1 70; 1 70], {'1', '1 -0.5'});
                if isempty(coeffs), return; end
                b = str2num(coeffs{1}); a = str2num(coeffs{2});
            else
                b = coeffs_file(1, :); a = coeffs_file(2, :);
            end

            % Implementación IIR (Ecuación de diferencia) [cite: 169]
            y = filter(b, a, x);
            metodo = 'IIR (Ecuacion de Diferencia)';
    end

    app.canalSalida1 = y; % Guardar resultado en la propiedad de la App

    
    figFiltro = figure('Name', metodo, 'NumberTitle', 'off', 'Color', [0.1 0.1 0.1]);
    ax1 = subplot(2,1,1, 'Parent', figFiltro);
    plot(ax1, t, x, 'Color', 'cyan'); title(ax1, 'Entrada', 'Color', 'w');
    xlabel(ax1, 'Tiempo (s)'); ylabel(ax1, 'Amplitud');
    
    ax2 = subplot(2,1,2, 'Parent', figFiltro);
    plot(ax2, t, y, 'Color', 'g'); title(ax2, ['Salida ' metodo], 'Color', 'w');
    xlabel(ax2, 'Tiempo (s)'); ylabel(ax2, 'Amplitud');
    set([ax1, ax2], 'Color', 'k', 'XColor', 'w', 'YColor', 'w');
        end

        % Button pushed function: TransformacionesButton
        function TransformacionesButtonPushed(app, event)
          if isempty(app.DatosPreprocesados)
                uialert(app.UIFigure, 'Realice el preprocesamiento primero.', 'Error');
                return; 
            end
        
            seleccion = uiconfirm(app.UIFigure, 'Transformación:', 'Análisis', 'Options', {'Fourier', 'Coseno', 'Wavelet'});
            Fs = app.FsActual; 
            x1 = app.DatosPreprocesados(:,1); 
            x2 = app.DatosPreprocesados(:,2);
            N = length(x1); 
            t = (0:N-1)/Fs;
        
            figT = figure('Name', ['Análisis: ' seleccion], 'Color', [0.1 0.1 0.1]);
        
            ax1 = subplot(3,2,1);
            stem(ax1, t, x1, 'Color', [0 0.7 0.9], 'MarkerSize', 3, 'LineWidth', 0.6);
            title(ax1, 'Entrada Preprocesada C1', 'Color', 'w');
            xlabel(ax1, 'Tiempo (s)'); ylabel(ax1, 'Amplitud');
        
            ax2 = subplot(3,2,2);
            stem(ax2, t, x2, 'Color', [0.9 0.4 0], 'MarkerSize', 3, 'LineWidth', 0.6);
            title(ax2, 'Entrada Preprocesada C2', 'Color', 'w');
            xlabel(ax2, 'Tiempo (s)'); ylabel(ax2, 'Amplitud');
        
            set([ax1, ax2], 'Color', 'k', 'XColor', 'w', 'YColor', 'w');
            grid(ax1, 'on'); grid(ax2, 'on');
        
            switch seleccion
                case "Fourier"
                    nfft = 2^nextpow2(N);
                    X1 = abs(fft(x1, nfft)); 
                    X2 = abs(fft(x2, nfft));
                    f = (0:nfft/2)*(Fs/nfft);
                    mag1 = X1(1:nfft/2+1)/N;
                    mag2 = X2(1:nfft/2+1)/N;
                    mag1(2:end-1) = 2*mag1(2:end-1);
                    mag2(2:end-1) = 2*mag2(2:end-1);
        
                    ax3 = subplot(3,2,[3,4,5,6]);
                    hold(ax3, 'on');
                    plot(ax3, f, mag1, 'Color', [0 0.7 0.9], 'LineWidth', 1);
                    plot(ax3, f, mag2, 'Color', [0.9 0.4 0],  'LineWidth', 1);
                    hold(ax3, 'off');
                    title(ax3, 'Espectro de Magnitud DFT (Cian: C1, Naranja: C2)', 'Color', 'w');
                    xlabel(ax3, 'Frecuencia (Hz)'); ylabel(ax3, 'Amplitud');
                    set(ax3, 'Color', 'k', 'XColor', 'w', 'YColor', 'w');
                    grid(ax3, 'on');
        
                    cla(app.axes_out);
                    hold(app.axes_out, 'on');
                    plot(app.axes_out, f, mag1, 'Color', [0 0.7 0.9], 'LineWidth', 1);
                    plot(app.axes_out, f, mag2, 'Color', [0.9 0.4 0],  'LineWidth', 1);
                    hold(app.axes_out, 'off');
                    title(app.axes_out, 'Salida Fourier: Espectro de Magnitud DFT', 'Color', 'w');
                    xlabel(app.axes_out, 'Frecuencia (Hz)'); ylabel(app.axes_out, 'Amplitud');
                    grid(app.axes_out, 'on');
        
                    app.canalSalida1 = mag1(:);
                    app.canalSalida2 = mag2(:);
        
                case "Coseno"
                    y_dct1 = dct(x1); 
                    y_dct2 = dct(x2);
                    corte = round(0.15 * N);
                    y_dct1_f = zeros(size(y_dct1)); y_dct1_f(1:corte) = y_dct1(1:corte);
                    y_dct2_f = zeros(size(y_dct2)); y_dct2_f(1:corte) = y_dct2(1:corte);
                    x_rec1 = idct(y_dct1_f);
                    x_rec2 = idct(y_dct2_f);
        
                    ax3 = subplot(3,2,[3,4]);
                    hold(ax3, 'on');
                    plot(ax3, abs(y_dct1), 'Color', [0 0.7 0.9], 'LineWidth', 1);
                    plot(ax3, abs(y_dct1_f), 'Color', [0.4 1 0.4], 'LineWidth', 1);
                    hold(ax3, 'off');
                    title(ax3, 'DCT C1: Original vs Filtrada', 'Color', 'w');
                    xlabel(ax3, 'Índice'); ylabel(ax3, 'Amplitud');
                    legend(ax3, {'Original','Filtrada'}, 'TextColor', 'w', 'Color', 'none');
                    set(ax3, 'Color', 'k', 'XColor', 'w', 'YColor', 'w');
                    grid(ax3, 'on');
        
                    ax4 = subplot(3,2,[5,6]);
                    hold(ax4, 'on');
                    stem(ax4, t, x1,     'Color', [0.5 0.5 0.5], 'MarkerSize', 2, 'LineWidth', 0.5);
                    stem(ax4, t, x_rec1, 'Color', [0.4 1 0.4],   'MarkerSize', 3, 'LineWidth', 0.6);
                    hold(ax4, 'off');
                    title(ax4, 'Entrada vs Reconstruida C1 (IDCT)', 'Color', 'w');
                    xlabel(ax4, 'Tiempo (s)'); ylabel(ax4, 'Amplitud');
                    set(ax4, 'Color', 'k', 'XColor', 'w', 'YColor', 'w');
                    grid(ax4, 'on');
        
                    cla(app.axes_out);
                    hold(app.axes_out, 'on');
                    stem(app.axes_out, t, x_rec1, 'Color', [0 0.7 0.9], 'MarkerSize', 3, 'LineWidth', 0.6);
                    stem(app.axes_out, t, x_rec2, 'Color', [0.9 0.4 0],  'MarkerSize', 3, 'LineWidth', 0.6);
                    hold(app.axes_out, 'off');
                    title(app.axes_out, 'Salida Coseno: Señal Reconstruida IDCT (Cian: C1, Naranja: C2)', 'Color', 'w');
                    xlabel(app.axes_out, 'Tiempo (s)'); ylabel(app.axes_out, 'Amplitud');
                    grid(app.axes_out, 'on');
        
                    app.canalSalida1       = x_rec1;
                    app.canalSalida2       = x_rec2;
                    app.canalEntrada1      = x_rec1;
                    app.canalEntrada2      = x_rec2;
                    app.DatosPreprocesados = [x_rec1(:), x_rec2(:)];
        
                case "Wavelet"
                    [c1, l1] = wavedec(x1, 3, 'db6');
                    [c2, l2] = wavedec(x2, 3, 'db6');
                    A3_c1 = appcoef(c1, l1, 'db6', 3); 
                    A3_c2 = appcoef(c2, l2, 'db6', 3);
                    rec1 = wrcoef('a', c1, l1, 'db6', 3);
                    rec2 = wrcoef('a', c2, l2, 'db6', 3);
        
                    ax3 = subplot(3,2,[3,5]);
                    stem(ax3, A3_c1, 'Color', [0 0.7 0.9], 'MarkerSize', 3, 'LineWidth', 0.6);
                    title(ax3, 'Aproximación A3 (C1)', 'Color', 'w');
                    xlabel(ax3, 'Muestras'); ylabel(ax3, 'Amplitud');
                    set(ax3, 'Color', 'k', 'XColor', 'w', 'YColor', 'w');
                    grid(ax3, 'on');
        
                    ax4 = subplot(3,2,[4,6]);
                    stem(ax4, A3_c2, 'Color', [0.9 0.4 0], 'MarkerSize', 3, 'LineWidth', 0.6);
                    title(ax4, 'Aproximación A3 (C2)', 'Color', 'w');
                    xlabel(ax4, 'Muestras'); ylabel(ax4, 'Amplitud');
                    set(ax4, 'Color', 'k', 'XColor', 'w', 'YColor', 'w');
                    grid(ax4, 'on');
        
                    cla(app.axes_out);
                    hold(app.axes_out, 'on');
                    stem(app.axes_out, t, rec1, 'Color', [0 0.7 0.9], 'MarkerSize', 3, 'LineWidth', 0.6);
                    stem(app.axes_out, t, rec2, 'Color', [0.9 0.4 0],  'MarkerSize', 3, 'LineWidth', 0.6);
                    hold(app.axes_out, 'off');
                    title(app.axes_out, 'Salida Wavelet: Reconstrucción desde A3 db6 (Cian: C1, Naranja: C2)', 'Color', 'w');
                    xlabel(app.axes_out, 'Tiempo (s)'); ylabel(app.axes_out, 'Amplitud');
                    grid(app.axes_out, 'on');
        
                    app.canalSalida1       = rec1;
                    app.canalSalida2       = rec2;
                    app.canalEntrada1      = rec1;
                    app.canalEntrada2      = rec2;
                    app.DatosPreprocesados = [rec1(:), rec2(:)];
            end
        end

        % Button pushed function: FiltrosButton
        function FiltrosButtonPushed(app, event)
            if isempty(app.DatosPreprocesados)
                uialert(app.UIFigure, 'Realice el preprocesamiento primero.', 'Error');
                return; 
            end
        
            Fs = app.FsActual;
            x1 = app.DatosPreprocesados(:,1); 
            x2 = app.DatosPreprocesados(:,2);
            t = (0:length(x1)-1)/Fs;
        
            origen     = uiconfirm(app.UIFigure, '¿Origen de parámetros?', 'Configuración', 'Options', {'Ventana', 'Archivo'});
            tipoFiltro = uiconfirm(app.UIFigure, 'Tipo de sistema:', 'Filtros', 'Options', {'Sistema FIR', 'Sistema IIR'});
        
            if strcmp(tipoFiltro, 'Sistema FIR')
                if strcmp(origen, 'Ventana')
                    h_str = inputdlg('Coeficientes h(n):', 'FIR', [1 60], {'0.2 0.5 0.2'});
                    if isempty(h_str), return; end
                    h = str2num(h_str{1});
                else
                    [file, path] = uigetfile('*.txt');
                    h = importdata(fullfile(path, file));
                end
                y1 = conv(x1, h(:), 'same');
                y2 = conv(x2, h(:), 'same');
                metodo = 'FIR (Convolución)';
            else
                if strcmp(origen, 'Ventana')
                    coeffs = inputdlg({'Numerador (b):', 'Denominador (a):'}, 'IIR', [1 60; 1 60], {'1', '1 -0.5'});
                    if isempty(coeffs), return; end
                    b = str2num(coeffs{1}); a = str2num(coeffs{2});
                else
                    [file, path] = uigetfile('*.txt');
                    data = importdata(fullfile(path, file));
                    b = data(1,:); a = data(2,:);
                end
                y1 = filter(b, a, x1);
                y2 = filter(b, a, x2);
                metodo = 'IIR (Ec. Diferencia)';
            end
        
            figF = figure('Name', ['Resultado: ' metodo], 'Color', [0.1 0.1 0.1]);
        
            axIn1 = subplot(2,2,1, 'Parent', figF);
            stem(axIn1, t, x1, 'Color', [0 0.7 0.9], 'MarkerSize', 3, 'LineWidth', 0.6);
            title(axIn1, 'Entrada Preprocesada: Canal 1', 'Color', 'w');
            xlabel(axIn1, 'Tiempo (s)'); ylabel(axIn1, 'Amplitud');
        
            axIn2 = subplot(2,2,2, 'Parent', figF);
            stem(axIn2, t, x2, 'Color', [0.9 0.4 0], 'MarkerSize', 3, 'LineWidth', 0.6);
            title(axIn2, 'Entrada Preprocesada: Canal 2', 'Color', 'w');
            xlabel(axIn2, 'Tiempo (s)'); ylabel(axIn2, 'Amplitud');
        
            axOut = subplot(2,2,[3,4], 'Parent', figF);
            hold(axOut, 'on');
            stem(axOut, t, y1, 'Color', [0 0.7 0.9], 'MarkerSize', 3, 'LineWidth', 0.6);
            stem(axOut, t, y2, 'Color', [0.9 0.4 0],  'MarkerSize', 3, 'LineWidth', 0.6);
            hold(axOut, 'off');
            title(axOut, ['Salida ' metodo ' (Cian: C1, Naranja: C2)'], 'Color', 'w');
            xlabel(axOut, 'Tiempo (s)'); ylabel(axOut, 'Amplitud');
        
            set([axIn1, axIn2, axOut], 'Color', 'k', 'XColor', 'w', 'YColor', 'w');
            grid(axIn1, 'on'); grid(axIn2, 'on'); grid(axOut, 'on');
        
            cla(app.axes_out);
            hold(app.axes_out, 'on');
            stem(app.axes_out, t, y1, 'Color', [0 0.7 0.9], 'MarkerSize', 3, 'LineWidth', 0.6);
            stem(app.axes_out, t, y2, 'Color', [0.9 0.4 0],  'MarkerSize', 3, 'LineWidth', 0.6);
            hold(app.axes_out, 'off');
            title(app.axes_out, ['Salida: ' metodo ' (Cian: C1, Naranja: C2)'], 'Color', 'w');
            xlabel(app.axes_out, 'Tiempo (s)'); ylabel(app.axes_out, 'Amplitud');
            grid(app.axes_out, 'on');
        
            app.canalSalida1       = y1;
            app.canalSalida2       = y2;
            app.canalEntrada1      = y1;
            app.canalEntrada2      = y2;
            app.DatosPreprocesados = [y1(:), y2(:)];
        end

        % Button pushed function: CargarDataset
        function CargarDatasetButtonPushed(app, event)
        filtros = {'*.mat;*.csv;*.npy', 'Todos los Datasets (*.mat, *.csv, *.npy)'};
            [file, path] = uigetfile(filtros, 'Seleccionar Dataset');
            if isequal(file, 0), return; end
            
            fullPath = fullfile(path, file);
            [~, ~, ext] = fileparts(file);
        
            try
                if strcmp(ext, '.mat')
                    dataStruct = load(fullPath);
                    nombres = fieldnames(dataStruct);
                    rawSignal = dataStruct.(nombres{1});
                elseif strcmp(ext, '.csv')
                    rawSignal = readmatrix(fullPath);
                elseif strcmp(ext, '.npy')
                    rawSignal = app.readNPY(fullPath); 
                end
        
                if size(rawSignal, 1) < size(rawSignal, 2)
                    rawSignal = rawSignal';
                end
        
                if size(rawSignal, 2) >= 2
                    app.canalEntrada1 = rawSignal(:, 1);
                    app.canalEntrada2 = rawSignal(:, 2);
                else
                    app.canalEntrada1 = rawSignal(:, 1);
                    app.canalEntrada2 = zeros(size(rawSignal,1), 1);
                end
        
                resp = inputdlg({'ID Sujeto:', 'Género (M/F):', 'Actividad:', 'Fs (Hz):'}, ...
                                'Metadatos', [1 45], {'S01', 'M', 'Movimiento', '1000'});
                
                if ~isempty(resp)
                    app.metadatosSujeto.ID       = resp{1};
                    app.metadatosSujeto.Genero   = resp{2};
                    app.metadatosSujeto.Actividad = resp{3};
                    app.frecMuestEntrada         = str2double(resp{4});
                    app.actualizarGraficosEntrada();
                end
        
            catch ME
                uialert(app.UIFigure, ['Error de carga: ' ME.message], 'Error');
            end
        end

        % Button pushed function: GrabarDataset
        function GrabarDatasetButtonPushed(app, event)
            GrabarNuevaGUI
        end

        % Button pushed function: GenerarSealSinteticaButton
        function GenerarSealSinteticaButtonPushed(app, event)
            tipos = {'Seno', 'Coseno', 'Sinc', 'Chirp'};
            [indx, ok] = listdlg('PromptString', 'Selecciona el tipo de señal:', ...
                                 'SelectionMode', 'single', ...
                                 'ListString', tipos, ...
                                 'Name', 'Tipo de Señal');
            if ok == 0
                return; 
            end 
            tipoSeleccionado = tipos{indx};
        
            prompt = {'Amplitud:', 'Duración (s):', 'Frecuencia Análoga (Hz):', 'Frecuencia de Muestreo (Hz):'};
            definput = {'1', '5', '1', '20'};
            
            if strcmp(tipoSeleccionado, 'Chirp')
                prompt{end+1} = 'Frecuencia Final (Chirp - Hz):';
                definput{end+1} = '10';
            end
        
            dlgtitle = 'Parámetros de la Señal Sintética';
            dims = [1 50];
            respuesta = inputdlg(prompt, dlgtitle, dims, definput);
            
            if isempty(respuesta)
                return; 
            end 
        
            A     = str2double(respuesta{1});
            Tfull = str2double(respuesta{2});
            Fa    = str2double(respuesta{3});
            Fs    = str2double(respuesta{4});
            
            if strcmp(tipoSeleccionado, 'Chirp')
                Ff = str2double(respuesta{5});
            else
                Ff = 0;
            end
        
            t = 0:(1/Fs):Tfull;
            out = [];
            
            switch tipoSeleccionado
                case 'Seno'
                    out = A * sin(2 * pi * Fa * t);
                case 'Coseno'
                    out = A * cos(2 * pi * Fa * t);
                case 'Sinc'
                    out = A * sinc(2 * Fa * (t - Tfull/2)); 
                case 'Chirp'
                    out = A * chirp(t, Fa, Tfull, Ff);
            end
        
            app.DatosGuardados = out;
            app.FsActual = Fs;
        
            app.DisplaySujeto.Value    = tipoSeleccionado;
            app.DisplayGenero.Value    = 'N/A';
            app.DisplayActividad.Value = 'N/A';
            app.DisplayFrecMuestreo.Value = num2str(Fs);
        
            cargarSenalEstereoEntrada(app, [out(:), out(:)], Fs);
            title(app.axes_in, ['Señal Sintética: ' tipoSeleccionado], 'Color', 'w');
        end

        % Button pushed function: RealizarPreprocesamientoButton
        function RealizarPreprocesamientoButtonPushed(app, event)
           x1 = app.canalEntrada1;
            x2 = app.canalEntrada2;
            fs_original = app.frecMuestEntrada;
        
            if isempty(x1)
                uialert(app.UIFigure, 'No hay señal cargada para procesar.', 'Error');
                return; 
            end
        
            x1 = x1(:); 
            x2 = x2(:);
            x_original_mat = [x1, x2];
        
            answer = inputdlg({'Nueva frecuencia de muestreo (Hz):'}, 'Ajuste Frecuencia', [1 45], {num2str(fs_original)});
            if isempty(answer), return; end
            fs_nueva = str2double(answer{1});
        
            if fs_nueva ~= fs_original && fs_nueva > 0
                [p, q] = rat(fs_nueva / fs_original);
                x_resampled = resample(x_original_mat, p, q);
            else
                x_resampled = x_original_mat;
                fs_nueva = fs_original;
            end
        
            mu = mean(x_resampled, 1);
            sigma = std(x_resampled, 0, 1);
            sigma(sigma == 0) = 1; 
            x_pre = (x_resampled - mu) ./ sigma;
        
            opciones = {'Hanning', 'Hamming', 'Rectangular'};
            [sel, ok] = listdlg('PromptString', 'Seleccione ventana:', 'ListString', opciones, 'SelectionMode', 'single');
            
            if ok
                N = size(x_pre, 1);
                switch opciones{sel}
                    case 'Hanning',     w = hann(N);
                    case 'Hamming',     w = hamming(N);
                    case 'Rectangular', w = rectwin(N);
                end
                x_final = x_pre .* w;
                nombre_ventana = opciones{sel};
            else
                x_final = x_pre;
                nombre_ventana = 'Ninguna';
            end
        
            t_original = (0:length(x1)-1) / fs_original;
            t_nuevo    = (0:length(x_final)-1) / fs_nueva;
        
            % --- axes_pre en la App (stem) ---
            cla(app.axes_pre);
            hold(app.axes_pre, 'on');
            stem(app.axes_pre, t_nuevo, x_final(:,1), 'Color', [0 0.7 0.9], 'MarkerSize', 3, 'LineWidth', 0.6);
            stem(app.axes_pre, t_nuevo, x_final(:,2), 'Color', [0.9 0.4 0],  'MarkerSize', 3, 'LineWidth', 0.6);
            hold(app.axes_pre, 'off');
            title(app.axes_pre, 'Señal Preprocesada Final', 'Color', 'w');
            xlabel(app.axes_pre, 'Tiempo (s)');
            ylabel(app.axes_pre, 'Amplitud');
            grid(app.axes_pre, 'on');
        
            % --- Dashboard externo (stem en los 3 subplots) ---
            fig = figure('Name', 'Dashboard de Preprocesamiento', 'Color', [0.1 0.1 0.1]);
            tlo = tiledlayout(3, 1, 'Parent', fig);
        
            % Subplot 1: Señal original (igual a axes_in)
            ax1 = nexttile(tlo);
            hold(ax1, 'on');
            stem(ax1, t_original, x1, 'Color', [0 0.7 0.9], 'MarkerSize', 3, 'LineWidth', 0.6);
            stem(ax1, t_original, x2, 'Color', [0.9 0.4 0],  'MarkerSize', 3, 'LineWidth', 0.6);
            hold(ax1, 'off');
            title(ax1, '1. Señal de Entrada Original', 'Color', 'w');
            legend(ax1, {'Canal 1', 'Canal 2'}, 'TextColor', 'w', 'Color', 'none');
        
            % Subplot 2: Z-Score tras remuestreo
            ax2 = nexttile(tlo);
            hold(ax2, 'on');
            stem(ax2, t_nuevo, x_pre(:,1), 'Color', [1 0.9 0],   'MarkerSize', 3, 'LineWidth', 0.6);
            stem(ax2, t_nuevo, x_pre(:,2), 'Color', [0.4 1 0.4], 'MarkerSize', 3, 'LineWidth', 0.6);
            hold(ax2, 'off');
            title(ax2, ['2. Z-Score (Remuestreo a ' num2str(fs_nueva) ' Hz)'], 'Color', 'w');
        
            % Subplot 3: Resultado final con ventana
            ax3 = nexttile(tlo);
            hold(ax3, 'on');
            stem(ax3, t_nuevo, x_final(:,1), 'Color', [0 0.7 0.9], 'MarkerSize', 3, 'LineWidth', 0.6);
            stem(ax3, t_nuevo, x_final(:,2), 'Color', [0.9 0.4 0],  'MarkerSize', 3, 'LineWidth', 0.6);
            hold(ax3, 'off');
            title(ax3, ['3. Resultado Final (Ventana: ' nombre_ventana ')'], 'Color', 'w');
            xlabel(ax3, 'Tiempo (s)');
        
            % Formato general
            set([ax1, ax2, ax3], 'Color', 'k', 'XColor', 'w', 'YColor', 'w');
            grid(ax1, 'on'); grid(ax2, 'on'); grid(ax3, 'on');
        
            % Guardar resultados
            app.DatosPreprocesados = x_final;
            app.FsActual = fs_nueva;
            app.DisplayFrecMuestreo.Value = num2str(fs_nueva);
        end

        % Button pushed function: GuardarSalida
        function GuardarSalidaButtonPushed(app, event)
            % Verificar que haya al menos señal de entrada
            if isempty(app.canalEntrada1)
                uialert(app.UIFigure, 'No hay señal para guardar.', 'Aviso');
                return;
            end
        
            % Preguntar qué señal guardar
            seleccion = uiconfirm(app.UIFigure, ...
                '¿Qué señal desea guardar?', 'Guardar Señal', ...
                'Options', {'Señal de Entrada', 'Señal de Salida (Procesada)'});
        
            if strcmp(seleccion, 'Señal de Salida (Procesada)')
                if isempty(app.canalSalida1)
                    uialert(app.UIFigure, 'No hay señal de salida procesada.', 'Aviso');
                    return;
                end
                datos = [app.canalSalida1(:), app.canalSalida2(:)];
                nombreDefault = 'senal_salida';
            else
                datos = [app.canalEntrada1(:), app.canalEntrada2(:)];
                nombreDefault = 'senal_entrada';
            end
        
            [file, path] = uiputfile({'*.mat','MAT-file'; '*.csv','CSV'}, ...
                                      'Guardar señal', nombreDefault);
            if isequal(file, 0), return; end
        
            [~, ~, ext] = fileparts(file);
            fullPath = fullfile(path, file);
        
            if strcmp(ext, '.mat')
                senal = datos; %#ok<NASGU>
                save(fullPath, 'senal');
            else
                writematrix(datos, fullPath);
            end
        
            uialert(app.UIFigure, 'Señal guardada correctamente.', 'Éxito');
        end

        % Button pushed function: DominioTButton
        function DominioTButtonPushed(app, event)
            if isempty(app.DatosPreprocesados)
                uialert(app.UIFigure, 'Realice el preprocesamiento primero.', 'Error');
                return;
            end
        
            Fs = app.FsActual;
            x1 = app.DatosPreprocesados(:,1);
            x2 = app.DatosPreprocesados(:,2);
            N  = length(x1);
            t  = (0:N-1)/Fs;
        
            operacion = uiconfirm(app.UIFigure, ...
                'Seleccione la operación en dominio del tiempo:', ...
                'Dominio del Tiempo', ...
                'Options', {'Cambio de Frecuencia de Muestreo', 'Desplazamiento en Tiempo', 'Ajuste de Magnitud'});
        
            switch operacion
        
                case 'Cambio de Frecuencia de Muestreo'
                    resp = inputdlg({'Nueva frecuencia de muestreo (Hz):'}, ...
                                    'Cambio Fs', [1 50], {num2str(Fs)});
                    if isempty(resp), return; end
                    Fs_nueva = str2double(resp{1});
        
                    if Fs_nueva <= 0 || isnan(Fs_nueva)
                        uialert(app.UIFigure, 'Frecuencia inválida.', 'Error');
                        return;
                    end
        
                    [p, q] = rat(Fs_nueva / Fs);
                    y1 = resample(x1, p, q);
                    y2 = resample(x2, p, q);
                    t_salida = (0:length(y1)-1) / Fs_nueva;
        
                    if Fs_nueva > Fs
                        tipo_str = 'Sobremuestreo';
                    else
                        tipo_str = 'Submuestreo';
                    end
                    titulo_op = [tipo_str ' (' num2str(Fs) ' Hz → ' num2str(Fs_nueva) ' Hz)'];
        
                    app.FsActual = Fs_nueva;
                    app.DisplayFrecMuestreo.Value = num2str(Fs_nueva);
        
                case 'Desplazamiento en Tiempo'
                    resp = inputdlg({'Desplazamiento en muestras (positivo=atraso, negativo=adelanto):'}, ...
                                    'Desplazamiento', [1 60], {'10'});
                    if isempty(resp), return; end
                    desp = round(str2double(resp{1}));
        
                    if isnan(desp)
                        uialert(app.UIFigure, 'Valor inválido.', 'Error');
                        return;
                    end
        
                    if desp >= 0
                        y1 = [zeros(desp,1); x1(1:end-desp)];
                        y2 = [zeros(desp,1); x2(1:end-desp)];
                        tipo_str = ['Atraso de ' num2str(desp) ' muestras'];
                    else
                        d = abs(desp);
                        y1 = [x1(d+1:end); zeros(d,1)];
                        y2 = [x2(d+1:end); zeros(d,1)];
                        tipo_str = ['Adelanto de ' num2str(d) ' muestras'];
                    end
                    titulo_op = ['Desplazamiento: ' tipo_str];
                    t_salida  = t;
        
                case 'Ajuste de Magnitud'
                    resp = inputdlg({'Constante de escala (multiplica la señal):'}, ...
                                    'Ajuste de Magnitud', [1 50], {'2'});
                    if isempty(resp), return; end
                    K = str2double(resp{1});
        
                    if isnan(K)
                        uialert(app.UIFigure, 'Valor inválido.', 'Error');
                        return;
                    end
        
                    y1 = K * x1;
                    y2 = K * x2;
                    titulo_op = ['Ajuste de Magnitud: x' num2str(K)];
                    t_salida  = t;
            end
        
            figDT = figure('Name', ['Dominio del Tiempo: ' titulo_op], 'Color', [0.1 0.1 0.1]);
        
            axEnt1 = subplot(3,2,1, 'Parent', figDT);
            stem(axEnt1, t, x1, 'Color', [0 0.7 0.9], 'MarkerSize', 3, 'LineWidth', 0.6);
            title(axEnt1, 'Entrada C1 (Preprocesada)', 'Color', 'w');
            xlabel(axEnt1, 'Tiempo (s)'); ylabel(axEnt1, 'Amplitud');
        
            axEnt2 = subplot(3,2,2, 'Parent', figDT);
            stem(axEnt2, t, x2, 'Color', [0.9 0.4 0], 'MarkerSize', 3, 'LineWidth', 0.6);
            title(axEnt2, 'Entrada C2 (Preprocesada)', 'Color', 'w');
            xlabel(axEnt2, 'Tiempo (s)'); ylabel(axEnt2, 'Amplitud');
        
            axSal = subplot(3,2,[3,4,5,6], 'Parent', figDT);
            hold(axSal, 'on');
            stem(axSal, t_salida, y1, 'Color', [0 0.7 0.9], 'MarkerSize', 3, 'LineWidth', 0.6);
            stem(axSal, t_salida, y2, 'Color', [0.9 0.4 0],  'MarkerSize', 3, 'LineWidth', 0.6);
            hold(axSal, 'off');
            title(axSal, ['Salida: ' titulo_op ' (Cian: C1, Naranja: C2)'], 'Color', 'w');
            xlabel(axSal, 'Tiempo (s)'); ylabel(axSal, 'Amplitud');
        
            set([axEnt1, axEnt2, axSal], 'Color', 'k', 'XColor', 'w', 'YColor', 'w');
            grid(axEnt1, 'on'); grid(axEnt2, 'on'); grid(axSal, 'on');
        
            cla(app.axes_out);
            hold(app.axes_out, 'on');
            stem(app.axes_out, t_salida, y1, 'Color', [0 0.7 0.9], 'MarkerSize', 3, 'LineWidth', 0.6);
            stem(app.axes_out, t_salida, y2, 'Color', [0.9 0.4 0],  'MarkerSize', 3, 'LineWidth', 0.6);
            hold(app.axes_out, 'off');
            title(app.axes_out, ['Salida Dominio Tiempo: ' titulo_op], 'Color', 'w');
            xlabel(app.axes_out, 'Tiempo (s)'); ylabel(app.axes_out, 'Amplitud');
            grid(app.axes_out, 'on');
        
            app.canalSalida1       = y1;
            app.canalSalida2       = y2;
            app.canalEntrada1      = y1;
            app.canalEntrada2      = y2;
            app.DatosPreprocesados = [y1(:), y2(:)];
        end

        % Button pushed function: BorrarSealesButton
        function BorrarSealesButtonPushed(app, event)
            confirmacion = uiconfirm(app.UIFigure, ...
                '¿Está seguro de que desea borrar todas las señales?', ...
                'Confirmar borrado', ...
                'Options', {'Sí, borrar', 'Cancelar'}, ...
                'DefaultOption', 'Cancelar', ...
                'CancelOption', 'Cancelar');
        
            if strcmp(confirmacion, 'Cancelar')
                return;
            end
        
            % Limpiar gráficas
            cla(app.axes_in);
            cla(app.axes_pre);
            cla(app.axes_out);
        
            % Limpiar títulos
            title(app.axes_in,  'Señal de Entrada',          'Color', 'w');
            title(app.axes_pre, 'Señal Preprocesada',         'Color', 'w');
            title(app.axes_out, 'Señal de Salida (Procesada)','Color', 'w');
        
            % Limpiar displays
            app.DisplaySujeto.Value       = '';
            app.DisplayGenero.Value       = '';
            app.DisplayActividad.Value    = '';
            app.DisplayFrecMuestreo.Value = '';
        
            % Limpiar datos en memoria
            app.canalEntrada1      = [];
            app.canalEntrada2      = [];
            app.canalSalida1       = [];
            app.canalSalida2       = [];
            app.DatosGuardados     = [];
            app.DatosPreprocesados = [];
            app.FsActual           = [];
            app.frecMuestEntrada   = [];
            app.metadatosSujeto    = struct();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axes_in = uiaxes(app.UIFigure);
            title(app.axes_in, 'Señal de Entrada')
            xlabel(app.axes_in, 'Tiempo (seg)')
            ylabel(app.axes_in, 'Amplitud')
            zlabel(app.axes_in, 'Z')
            app.axes_out = uiaxes(app.UIFigure);
            title(app.axes_out, 'Señal de Salida (Procesada)')
            xlabel(app.axes_out, 'Tiempo (seg)')
            ylabel(app.axes_out, 'Amplitud')
            zlabel(app.axes_out, 'Z')
            app.axes_pre = uiaxes(app.UIFigure);
            title(app.axes_pre, 'Señal Preprocesada')
            xlabel(app.axes_pre, 'Tiempo (seg)')
            ylabel(app.axes_pre, 'Amplitud')
            zlabel(app.axes_pre, 'Z')
            app.SujetoEditFieldLabel = uilabel(app.UIFigure);
            app.SujetoEditFieldLabel.Text = 'Sujeto';
            app.DisplaySujeto = uieditfield(app.UIFigure, 'text');
            app.DisplaySujeto.Editable = 'off';
            app.FrecuenciadeMuestreoEditFieldLabel = uilabel(app.UIFigure);
            app.FrecuenciadeMuestreoEditFieldLabel.Text = 'Frecuencia de Muestreo';
            app.DisplayFrecMuestreo = uieditfield(app.UIFigure, 'text');
            app.DisplayFrecMuestreo.Editable = 'off';
            app.SealdeAudioPanel = uipanel(app.UIFigure);
            app.SealdeAudioPanel.Title = 'Señal de Audio';
            app.Lamp = uilamp(app.SealdeAudioPanel);
            app.Lamp.Enable = 'off';
            app.EscucharAudio = uibutton(app.SealdeAudioPanel, 'push');
            app.EscucharAudio.ButtonPushedFcn = createCallbackFcn(app, @EscucharAudioButtonPushed, true);
            app.EscucharAudio.Text = 'Escuchar';
            app.CargarAudio = uibutton(app.SealdeAudioPanel, 'push');
            app.CargarAudio.ButtonPushedFcn = createCallbackFcn(app, @CargarAudioButtonPushed, true);
            app.CargarAudio.Text = 'Cargar';
            app.GrabarButton = uibutton(app.SealdeAudioPanel, 'state');
            app.GrabarButton.ValueChangedFcn = createCallbackFcn(app, @GrabarButtonValueChanged, true);
            app.GrabarButton.Text = 'Grabar';
            app.PreprocesamientoPanel = uipanel(app.UIFigure);
            app.PreprocesamientoPanel.Title = 'Preprocesamiento';
            app.RealizarPreprocesamientoButton = uibutton(app.PreprocesamientoPanel, 'push');
            app.RealizarPreprocesamientoButton.ButtonPushedFcn = createCallbackFcn(app, @RealizarPreprocesamientoButtonPushed, true);
            app.RealizarPreprocesamientoButton.Text = 'Realizar Preprocesamiento';
            app.SealDatasetPanel = uipanel(app.UIFigure);
            app.SealDatasetPanel.Title = 'Señal Dataset';
            app.GrabarDataset = uibutton(app.SealDatasetPanel, 'push');
            app.GrabarDataset.ButtonPushedFcn = createCallbackFcn(app, @GrabarDatasetButtonPushed, true);
            app.GrabarDataset.Text = 'Grabar Nueva';
            app.CargarDataset = uibutton(app.SealDatasetPanel, 'push');
            app.CargarDataset.ButtonPushedFcn = createCallbackFcn(app, @CargarDatasetButtonPushed, true);
            app.CargarDataset.Text = 'Cargar';
            app.ProcesamientoPanel = uipanel(app.UIFigure);
            app.ProcesamientoPanel.Title = 'Procesamiento';
            app.TransformacionesButton = uibutton(app.ProcesamientoPanel, 'push');
            app.TransformacionesButton.ButtonPushedFcn = createCallbackFcn(app, @TransformacionesButtonPushed, true);
            app.TransformacionesButton.Text = 'Transformaciones';
            app.FiltrosButton = uibutton(app.ProcesamientoPanel, 'push');
            app.FiltrosButton.ButtonPushedFcn = createCallbackFcn(app, @FiltrosButtonPushed, true);
            app.FiltrosButton.Text = 'Filtros';
            app.DominioTButton = uibutton(app.ProcesamientoPanel, 'push');
            app.DominioTButton.ButtonPushedFcn = createCallbackFcn(app, @DominioTButtonPushed, true);
            app.DominioTButton.Text = 'DominioT';
            app.SealSintticaPanel = uipanel(app.UIFigure);
            app.SealSintticaPanel.Title = 'Señal Sintética';
            app.GenerarSealSinteticaButton = uibutton(app.SealSintticaPanel, 'push');
            app.GenerarSealSinteticaButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarSealSinteticaButtonPushed, true);
            app.GenerarSealSinteticaButton.Text = 'Generar Señal Sintetica';
            app.Panel = uipanel(app.UIFigure);
            app.GuardarSalida = uibutton(app.Panel, 'push');
            app.GuardarSalida.ButtonPushedFcn = createCallbackFcn(app, @GuardarSalidaButtonPushed, true);
            app.GuardarSalida.Text = 'Guardar Señales';
            app.BorrarSealesButton = uibutton(app.Panel, 'push');
            app.BorrarSealesButton.ButtonPushedFcn = createCallbackFcn(app, @BorrarSealesButtonPushed, true);
            app.BorrarSealesButton.Text = 'Borrar Señales';
            app.IdentificadorLabel_2 = uilabel(app.UIFigure);
            app.IdentificadorLabel_2.Text = 'Genero';
            app.DisplayGenero = uieditfield(app.UIFigure, 'text');
            app.DisplayGenero.Editable = 'off';
            app.ActividadLabel = uilabel(app.UIFigure);
            app.ActividadLabel.Text = 'Actividad';
            app.DisplayActividad = uieditfield(app.UIFigure, 'text');
            app.DisplayActividad.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterfazFinal2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end